classdef APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConfigsTab                    matlab.ui.container.Tab
        ProcessarimagemdosinalButton  matlab.ui.control.Button
        SelecionarfotoButton          matlab.ui.control.Button
        PasswordEditField             matlab.ui.control.EditField
        PasswordEditField_2Label      matlab.ui.control.Label
        StopLiveButton                matlab.ui.control.Button
        CalibrarARENAButton           matlab.ui.control.Button
        BS_V_max                      matlab.ui.control.NumericEditField
        BS_V_min                      matlab.ui.control.NumericEditField
        BS_S_max                      matlab.ui.control.NumericEditField
        BS_S_min                      matlab.ui.control.NumericEditField
        BS_H_max                      matlab.ui.control.NumericEditField
        BS_H_min                      matlab.ui.control.NumericEditField
        AS_V_max                      matlab.ui.control.NumericEditField
        AS_V_min                      matlab.ui.control.NumericEditField
        AS_S_max                      matlab.ui.control.NumericEditField
        AS_S_min                      matlab.ui.control.NumericEditField
        AS_H_max                      matlab.ui.control.NumericEditField
        AS_H_min                      matlab.ui.control.NumericEditField
        VS_V_max                      matlab.ui.control.NumericEditField
        VS_V_min                      matlab.ui.control.NumericEditField
        VS_S_max                      matlab.ui.control.NumericEditField
        VS_S_min                      matlab.ui.control.NumericEditField
        VS_H_max                      matlab.ui.control.NumericEditField
        VS_H_min                      matlab.ui.control.NumericEditField
        LM_V_max                      matlab.ui.control.NumericEditField
        LM_V_min                      matlab.ui.control.NumericEditField
        LM_S_max                      matlab.ui.control.NumericEditField
        LM_S_min                      matlab.ui.control.NumericEditField
        LM_H_max                      matlab.ui.control.NumericEditField
        LM_H_min                      matlab.ui.control.NumericEditField
        GM_V_max                      matlab.ui.control.NumericEditField
        GM_V_min                      matlab.ui.control.NumericEditField
        GM_S_max                      matlab.ui.control.NumericEditField
        GM_S_min                      matlab.ui.control.NumericEditField
        GM_H_max                      matlab.ui.control.NumericEditField
        GM_H_min                      matlab.ui.control.NumericEditField
        AM_V_max                      matlab.ui.control.NumericEditField
        AM_V_min                      matlab.ui.control.NumericEditField
        AM_S_max                      matlab.ui.control.NumericEditField
        AM_S_min                      matlab.ui.control.NumericEditField
        AM_H_max                      matlab.ui.control.NumericEditField
        AM_H_min                      matlab.ui.control.NumericEditField
        RM_V_max                      matlab.ui.control.NumericEditField
        RM_V_min                      matlab.ui.control.NumericEditField
        RM_S_max                      matlab.ui.control.NumericEditField
        RM_S_min                      matlab.ui.control.NumericEditField
        RM_H_max                      matlab.ui.control.NumericEditField
        RM_H_min                      matlab.ui.control.NumericEditField
        BM_V_max                      matlab.ui.control.NumericEditField
        BM_V_min                      matlab.ui.control.NumericEditField
        BM_S_max                      matlab.ui.control.NumericEditField
        BM_S_min                      matlab.ui.control.NumericEditField
        BM_H_max                      matlab.ui.control.NumericEditField
        BM_H_min                      matlab.ui.control.NumericEditField
        CalibrarBrancoButton          matlab.ui.control.Button
        CalibrarVERMELHOButton        matlab.ui.control.Button
        CalibrarAZULButton            matlab.ui.control.Button
        CalibrarBASE2Button           matlab.ui.control.Button
        CalibrarBASE4Button           matlab.ui.control.Button
        CalibrarBASE3Button           matlab.ui.control.Button
        CalibrarBASE1Button           matlab.ui.control.Button
        VmaxLabel                     matlab.ui.control.Label
        VminLabel                     matlab.ui.control.Label
        SmaxLabel                     matlab.ui.control.Label
        SminLabel                     matlab.ui.control.Label
        HmaxLabel                     matlab.ui.control.Label
        HminLabel                     matlab.ui.control.Label
        SINAISLabel                   matlab.ui.control.Label
        BrancoLabel_2                 matlab.ui.control.Label
        LilasLabel                    matlab.ui.control.Label
        AmareloLabel                  matlab.ui.control.Label
        VerdeLabel                    matlab.ui.control.Label
        VermelhoLabel_2               matlab.ui.control.Label
        MAPALabel                     matlab.ui.control.Label
        VermelhoLabel                 matlab.ui.control.Label
        AzulLabel                     matlab.ui.control.Label
        BrancoLabel                   matlab.ui.control.Label
        ProcessarimagemdomapaButton   matlab.ui.control.Button
        TresholdsLabel                matlab.ui.control.Label
        UserEditField                 matlab.ui.control.EditField
        UserEditFieldLabel            matlab.ui.control.Label
        IPEditField                   matlab.ui.control.EditField
        IPEditFieldLabel              matlab.ui.control.Label
        CapturarfotoButton            matlab.ui.control.Button
        StartLiveButton               matlab.ui.control.Button
        CamaraconnectionTresholdseArenaLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        Image_2                       matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        Image_4                       matlab.ui.control.Image
        Image_5                       matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
        RunTab                        matlab.ui.container.Tab
        PasswordEditField_2           matlab.ui.control.EditField
        PasswordEditField_2Label_2    matlab.ui.control.Label
        UserEditField_2               matlab.ui.control.EditField
        UserEditField_2Label          matlab.ui.control.Label
        IPEditField_2                 matlab.ui.control.EditField
        IPEditField_2Label            matlab.ui.control.Label
        CamaraconnectionSinaisLabel   matlab.ui.control.Label
        Image2_2                      matlab.ui.control.Image
        CarregarmapaButton            matlab.ui.control.Button
        LogTextArea                   matlab.ui.control.TextArea
        Image3                        matlab.ui.control.Image
        STARTButton                   matlab.ui.control.Button
        UIAxes_Mascara_Branco         matlab.ui.control.UIAxes
        UIAxes_Mascara_Azul           matlab.ui.control.UIAxes
        UIAxes_Mascara_Vermelho       matlab.ui.control.UIAxes
        UIAxes_Mapa_Simuldo           matlab.ui.control.UIAxes
        UIAxes_Sinal_Foto_Original    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        live = false;
        video;
        hLiveImg;
        freeZoneMap;
        waypointsBases_px;
        origin_px;
        mmPerPixel;
        viz;
        InflatedBases;
        robot;
        lastSnapshotRGB;
        imgFile;
        robotCentroid_px;
        theta0;
        boardMask;
        centroideYellow;
        centroidePurple;
        cantosBases;
        processedBases;
        BasesCorrection;
        newimg = 1;
    end
    
    methods (Access = private)
%% Cria um objeto ipcam a partir de um IP/URL e credenciais
        function camera = LigarCamera(~, ip, user, pass)
            ip   = strtrim(string(ip));
            user = strtrim(string(user));
            pass = strtrim(string(pass));
        
            % Se o utilizador já meteu um URL completo, usa-o
            if startsWith(lower(ip), "http://") || startsWith(lower(ip), "https://")
                base = ip;
            else
                % Se meteu "IP:PORTA"
                if contains(ip, ":")
                    base = "http://" + ip;
                else
                    base = "http://" + ip + ":8080";
                end
            end
        
            % Se já existir /video não duplica
            if endsWith(lower(base), "/video")
                url = base;
            else
                url = base + "/video";
            end
        
            % Aumenta timeout
             camera = ipcam(url, user, pass, "Timeout", 20);
        end

%% Processa uma imagem RGB top-down da arena e produz mapa navegável + referências
        function [freeZoneMap, waypointsBases, InflatedBases, robotCentroid, mmPerPixel,boardMask,centroidPurple,centroidYellow,cantosBases] = ProcessedMap(app, map, debugPlot)
            % Logger local (centraliza o “print” na UI do App)
            logFcn = @(s) app.Log(s);
        
            % Ler imagem e converter para HSV
            originalImage = imread(map);
            hsvImage = rgb2hsv(originalImage);
        
            H = hsvImage(:,:,1);
            S = hsvImage(:,:,2);
            Vraw = hsvImage(:,:,3);
            V = Vraw;
            
            % Deteta arena pelo branco (boardMask)
            BM = getHSVthr(app,'BM');                   % Threshold HSV do "branco" da arena
            whiteCand = hsvMask(app, H, S, Vraw, BM);
            
            whiteCand = imclearborder(whiteCand);       % Remove componentes ligadas às bordas
        
            % Limpeza morfológica do branco
            whiteCand = bwareaopen(whiteCand, 5000);
            whiteCand = imclose(whiteCand, strel('disk', 15));
            whiteCand = imfill(whiteCand, 'holes');
        
            % Ficar com o maior objeto branco -> arena
            Lw = bwlabel(whiteCand);
            wp = regionprops(Lw,'Area');
            if isempty(wp)
                warning("ProcessedMap:BoardNotFound", "Não foi possível detetar o tabuleiro (whiteCand vazio).");
                boardMask = false(size(whiteCand));
            else
                [~, idxW] = max([wp.Area]);
                boardMask = (Lw == idxW);
            end
        
            % Arena e moldura
            arenaMask = imdilate(boardMask, strel('disk', 10));
            delimitationMask = arenaMask & ~imerode(arenaMask, strel('disk', 8));
            
            % Segmentação das bases por cor (Dentro da arena)
            RM = getHSVthr(app,'RM'); % base vermelha
            AM = getHSVthr(app,'AM'); % base amarela
            GM = getHSVthr(app,'GM'); % base verde
            LM = getHSVthr(app,'LM'); % base lilás/roxa
        
            colorMask = cell(1,4);
            colorMask{1} = hsvMask(app, H,S,V, LM) & boardMask; % Lilás (também pode apanhar marcador roxo do robô)
            colorMask{2} = hsvMask(app, H,S,V, AM) & boardMask; % Amarelo (também pode apanhar marcador amarelo do robô)
            colorMask{3} = hsvMask(app, H,S,V, GM) & boardMask; % Verde
            colorMask{4} = hsvMask(app, H,S,V, RM) & boardMask; % Vermelho
        
            % Processamento inicial das bases
            app.processedBases = cell(1,4);
            for i = 1:4
                app.processedBases{i} = bwareaopen(colorMask{i}, 800);                      % remove ruído
                app.processedBases{i} = imclose(app.processedBases{i}, strel('disk', 7));   % compacta buracos pequenos
                app.processedBases{i} = imfill(app.processedBases{i}, 'holes');             % garante base cheia
            end

            % DETEÇÃO DO ROBÔ (MÁSCARA PRETA + ROXO E AMARELO)
            % Máscara preta
            blackMaskRobot = (Vraw < 0.35) & boardMask;
            blackMaskRobot = imclose(blackMaskRobot, strel('disk', 15));
            blackMaskFilledRobot = bwareaopen(imfill(blackMaskRobot,"holes"), 200);
        
            % Centro do robô = circulo lilás
            purpleRobotCand = colorMask{1} & boardMask;
            purpleRobotCand = purpleRobotCand & blackMaskFilledRobot;
            purpleSquareMask = bwareaopen(purpleRobotCand, 30);
            purpleSquareMask = imclose(purpleSquareMask, strel('disk',20));
            purpleSquareMask = imfill(purpleSquareMask, "holes");
            
            % Marcador amarelo - em conjunto com o marcador roxo, permite estimar a orientação do robô
            yellowRobotCand = colorMask{2} & boardMask;
            yellowRobotCand = yellowRobotCand & blackMaskFilledRobot;
            yellowSquareMask = bwareaopen(yellowRobotCand, 30);
            yellowSquareMask = imclose(yellowSquareMask, strel('disk',20));
            yellowSquareMask = imfill(yellowSquareMask, "holes");
            
            % Máscara combinada dos marcadores
            robotSquaresMask = imdilate(yellowSquareMask | purpleSquareMask, strel('disk', 5));
        
            % Remover as rodas das máscaras de cor ANTES de processar as bases
            colorMask{2} = colorMask{2} & ~robotSquaresMask;  % Amarelo sem roda amarela
            colorMask{1} = colorMask{1} & ~robotSquaresMask;  % Roxo sem roda roxa
        
            % Reprocessar as bases SEM as rodas do robô
            for i = [1, 2]  % Apenas amarelo (2) e roxo (1) precisam ser reprocessados
                app.processedBases{i} = bwareaopen(colorMask{i}, 800);
                app.processedBases{i} = imclose(app.processedBases{i}, strel('disk', 7));
                app.processedBases{i} = imfill(app.processedBases{i}, 'holes');
            end
                
            % Centroides dos marcadores do robô (escolhe maior blob)
            propsPurple = regionprops(purpleSquareMask, 'Centroid', 'Area');
            propsYellow = regionprops(yellowSquareMask, 'Centroid', 'Area');
        
            % Selecionar os maiores objetos detetados (evita erros em caso de existir ruido)
            [~, idxPurple] = max([propsPurple.Area]);
            [~, idxYellow] = max([propsYellow.Area]);    
            centroidPurple = propsPurple(idxPurple).Centroid;
            centroidYellow = propsYellow(idxYellow).Centroid;
            
            % Referência para definir mmPerPixel
            propsBaseRed = regionprops(app.processedBases{4}, 'BoundingBox', 'Area');
            
            % Selecionar a maior área para garantir que é a base vermelha
            [~, idxRed] = max([propsBaseRed.Area]);
            bbox = propsBaseRed(idxRed).BoundingBox;    % [x, y, largura, altura]
                
            % Média da largura e altura em pixels (a base real tem 200mm)
            largura_px = bbox(3);
            altura_px = bbox(4);
            pixelDistanceBase = (largura_px + altura_px) / 2;
            
            % Cálculo: 200mm / média de pixeis do quadrado
            mmPerPixel = 200 / pixelDistanceBase;
            
            logFcn(sprintf('Calibração Chão (Base Vermelha): %.1f px = 200mm | Novo mmPerPixel = %.4f\n', pixelDistanceBase, mmPerPixel));
        
            % Centroide do robô é a média entre os dois marcadores
            robotCentroid = centroidPurple;
        
            % Dilatar bases pelo tamanho do robô
            allInflatedBases = false(size(H));
            InflatedBases = cell(1,4);
            for i = 1:4
                InflatedBases{i} = imdilate(app.processedBases{i}, strel('rectangle', [200,200]));
                allInflatedBases = allInflatedBases | InflatedBases{i};
            end

            app.BasesCorrection = allInflatedBases;
            
            % Zona navegável da arena
            freeZoneMap = (boardMask & ~allInflatedBases) | blackMaskFilledRobot;

            % Waypoints de vavegação e Manobra (Cantos)
            waypointsBases = cell(1,4);
            cantosBases = cell(1,4);
            
            for i = 1:4
                % BoundingBox das bases inflacionadas para definir pontos
                propsSafe = regionprops(InflatedBases{i}, 'BoundingBox', 'Centroid', 'Area');
                if isempty(propsSafe) 
                    continue; 
                end
                
                [~, idxSafe] = max([propsSafe.Area]);
                bbox = propsSafe(idxSafe).BoundingBox; % [x, y, largura, altura]
                centroid = propsSafe(idxSafe).Centroid;
                
                % Limites exatos da BoundingBox
                xMin = bbox(1);
                yMin = bbox(2);
                xMax = xMin + bbox(3);
                yMax = yMin + bbox(4);
                
                % PONTOS DE NAVEGAÇÃO (meio das bases)
                % Ordem: 1=N, 2=S, 3=O, 4=E
                waypointsBases{i} = [
                    centroid(1), yMin;  % Norte
                    centroid(1), yMax;  % Sul
                    xMin, centroid(2);  % Oeste
                    xMax, centroid(2)   % Este
                ];
                
                % PONTOS DE MANOBRA (Cantos exatos das bases)
                % Ordem: 1=NW, 2=NE, 3=SW, 4=SE
                cantosBases{i} = [
                    xMin, yMin; % NW (Canto Superior Esquerdo)
                    xMax, yMin; % NE (Canto Superior Direito)
                    xMin, yMax; % SW (Canto Inferior Esquerdo)
                    xMax, yMax  % SE (Canto Inferior Direito)
                ];
            end
                    
            % ----------------- DEBUG PLOT (apenas fora do App) -----------------
            if debugPlot
                figure('Name','ProcessedMap DEBUG (HSV + masks)');
                tiledlayout(3,5,'Padding','compact','TileSpacing','compact');
        
                nexttile; imshow(originalImage); title('Original');
                nexttile; imshow(H); title('H');
                nexttile; imshow(S); title('S');
                nexttile; imshow(Vraw); title('V raw');
                nexttile; imshow(V); title('V');
        
                nexttile; imshow(delimitationMask); title('delimitationMask');
                nexttile; imshow(boardMask); title('boardMask');
                nexttile; imshow(blackMaskFilledRobot);
                title(sprintf('robot mask (px=%d)', nnz(blackMaskFilledRobot)));
        
                nexttile; imshow(app.processedBases{1}); title('Base 1 (purple)');
                nexttile; imshow(app.processedBases{2}); title('Base 2 (yellow)');
        
                nexttile; imshow(app.processedBases{3}); title('Base 3 (green)');
                nexttile; imshow(app.processedBases{4}); title('Base 4 (red)');
                nexttile; imshow(allInflatedBases); title('inflated bases');
                nexttile; imshow(freeZoneMap); title('freeZoneMap');
        
                nexttile; imshow(originalImage); hold on;
                visboundaries(allInflatedBases,'LineWidth',2);
                if all(isfinite(robotCentroid))
                    plot(robotCentroid(1), robotCentroid(2), 'go','MarkerFaceColor','g');
                end
                title('Inflated overlay + robot');
        
                % Numerar bases no overlay
                for b = 1:4
                    stC = regionprops(app.processedBases{b}, 'Centroid', 'Area');
                    if isempty(stC), continue; end
                    [~, iC] = max([stC.Area]);
                    c = stC(iC).Centroid;
                    text(c(1), c(2), sprintf('%d', b), 'Color','w','FontWeight','bold', 'FontSize',14, ...
                        'HorizontalAlignment','center','VerticalAlignment','middle');
                end
            end
        end

%% Inicializa a figura de visualização do mapa (imagem original) e cria handles para desenhar
        function [freeZoneMap, waypointsBases, robotCentroid_px, mmPerPixel, viz,origin_px,theta0,cantosBases] = InitVizMapa(app, mapFile, ax)
            % UIAxes fallback
            if nargin < 3 || isempty(ax)
                ax = app.UIAxes_Mapa_Simuldo;
            end
        
            % Processar mapa (sem debug figures)
            [freeZoneMap, waypointsBases, app.InflatedBases, robotCentroid_px, mmPerPixel,boardeMask,centroidPurple, centroidYellow,cantosBases] = ProcessedMap(app, mapFile, false);
        
            img = imread(mapFile);
        
            % Desenhar no UIAxes
            cla(ax);
            imshow(img, 'Parent', ax);
            hold(ax, 'on');
            axis(ax, 'image');
            ax.XTick = [];
            ax.YTick = [];
        
            % Guardar info do viz
            viz.ax        = ax;
            viz.origin_px = robotCentroid_px(:).';
            viz.mPerPix   = mmPerPixel/1000;
        
            % Waypoints e centros das bases (px)
            for b = 1:numel(waypointsBases)
                wps = waypointsBases{b};
                plot(ax, wps(:,1), wps(:,2), 'ys', 'MarkerFaceColor','y', 'MarkerSize',6);
                c = mean(wps,1);
                plot(ax, c(1), c(2), 'ro', 'MarkerFaceColor','r', 'MarkerSize',6);
                text(ax, c(1)-25, c(2)-25, sprintf('Base %d',b), 'FontWeight','bold');
            end
        
            % Contornos das zonas dilatadas (overlay transparente)
            for b = 1:numel(app.InflatedBases)
                M = app.InflatedBases{b};
                h = imshow(ones(size(M)), 'Parent', ax);
                set(h, 'AlphaData', 0.15 * double(M));
            end
        
            % Robô na imagem (centróide em px)
            viz.hCam = plot(ax, robotCentroid_px(1), robotCentroid_px(2), ...
                'go','MarkerFaceColor','g','MarkerSize',8);
        
            % Caminho planeado + rasto real
            viz.hPlan      = animatedline('Parent', ax, 'LineWidth', 2, 'LineStyle', '--');
            viz.hRealTrail = animatedline('Parent', ax, 'LineWidth', 2);
        
            % Dimensões do robô (m)
            robotL_m = 0.20;
            robotW_m = 0.145;
            
            % Converter para pixels
            L_px = robotL_m / viz.mPerPix;
            W_px = robotW_m / viz.mPerPix;
        
            viz.robotL_px = L_px;
            viz.robotW_px = W_px;
            
            % Cantos do retângulo centrado no robô (2x4)
            viz.robotCorners0 = [ -L_px/2, -W_px/2;
                                   L_px/2, -W_px/2;
                                   L_px/2,  W_px/2;
                                  -L_px/2,  W_px/2 ]';
        
            c0 = viz.robotCorners0;
            viz.hReal = patch(ax, ...
                'XData', c0(1,:) + robotCentroid_px(1), ...
                'YData', c0(2,:) + robotCentroid_px(2), ...
                'FaceColor','none', 'EdgeColor','b', 'LineWidth',2);

            text(viz.ax, robotCentroid_px(1)+10, robotCentroid_px(2)+20, ...
                'ROBO (odometria)','Color','b','FontWeight','bold');
        
            % Limpar handle antigo da seta (se existir)
            try
                rmappdata(viz.ax, 'hHeading');
            catch
            end
        
            drawnow limitrate;
            
            % Origem px
            stats = regionprops(boardeMask, 'ConvexHull');
            hull = stats(1).ConvexHull;
            origin_px = [min(hull(:,1)), min(hull(:,2))];
            
            % theta0 a partir dos dois marcadores (roxo -> amarelo), com Y invertido
            dx = centroidPurple(1) - centroidYellow(1);
            dy = -(centroidPurple(2) - centroidYellow(2)); % Inversão do eixo Y da imagem
            theta0 = atan2(dy, dx);
            
            % Guardar
            app.origin_px = origin_px;
            app.theta0 = theta0;

            % --- Desenhar a Seta de Orientação Inicial ---
            % Definir o comprimento da seta em pixels
            headingLen_px = L_px * 0.7;
            u = headingLen_px * cos(theta0);
            v = -headingLen_px * sin(theta0); % Inversão para o eixo Y da imagem
            
            viz.hHeading = quiver(viz.ax, robotCentroid_px(1), robotCentroid_px(2), ...
                u, v, 0, 'Color', 'b', 'LineWidth', 2, 'MaxHeadSize', 2);
            
            % Guardar o handel no Axes
            setappdata(viz.ax, 'hHeading', viz.hHeading);
        
            % Guardar também no struct viz para acesso direto
            viz.origin_px = app.origin_px; 
            
            drawnow limitrate;
        end

%% Desenha/atualiza o caminho planeado (px) no UIAxes, limpando o anterior
        function VizPlotPlannedPath(~, viz, path_px)
            % Se não houver caminho (vazio), não faz nada para evitar erros
            if isempty(path_px) 
                return; 
            end
            
            % Apaga os pontos previamente desenhados do caminho planeado
            clearpoints(viz.hPlan);
            
            % Adiciona todos os pontos do novo caminho (em px) ao animatedline do planeado
            addpoints(viz.hPlan, path_px(:,1), path_px(:,2));
            
            % Atualiza a figura limitando a taxa de refresh
            drawnow limitrate;
        end

%% Atualiza no visualizador a posição "real" (odometria):
%  - m -> px
%  - atualiza retângulo do robô, rasto e seta de heading
        function VizUpdateRealPose(app, viz, pose_m)
        % pose_m = [x; y; theta] (m, m, rad)
        
            % posição (m) -> pixels
            p0_px = m2px(app,[pose_m(1) pose_m(2)], viz.origin_px, viz.mPerPix);
            px = p0_px(1);
            py = p0_px(2);
        
            % Rotação do retângulo (ajuste de sinal para eixo Y da imagem)
            theta = -(pose_m(3));
            R = [cos(theta) -sin(theta);
                 sin(theta)  cos(theta)];
            
            % Cantos rodados e transladados
            c = R * viz.robotCorners0;  % 2x4 cantos rodados
            set(viz.hReal, 'XData', c(1,:) + px, 'YData', c(2,:) + py);
            
            % Rasto
            addpoints(viz.hRealTrail, px, py);
        
            % Seta de orientação
            persistent hHeading
            if isempty(hHeading) || ~isgraphics(hHeading)
                ax = ancestor(viz.hReal, 'axes');
                hold(ax, 'on');
                hHeading = quiver(ax, px, py, 0, 0, 0, ...
                    'AutoScale', 'off', 'LineWidth', 2, 'MaxHeadSize', 2);
            end
        
            % Comprimento da seta em metros
            headingLen_m = 0.10;  % 10 cm   
        
            % Ponto final em metros (no frame da odometria)
            p1_m = [pose_m(1) + headingLen_m*cos(pose_m(3)), ...
                    pose_m(2) + headingLen_m*sin(pose_m(3))];
        
            % Converter ponto final para pixels e obter vetor em px
            p1_px = m2px(app, p1_m, viz.origin_px, viz.mPerPix);
            dpx = p1_px(1) - px;
            dpy = p1_px(2) - py;
        
            % Atualizar seta
            set(hHeading, 'XData', px, 'YData', py, 'UData', dpx, 'VData', dpy);
            
            % Atualiza a figura limitando a taxa de refresh
            drawnow limitrate;
        end

%% Configurações do robo
        function robot = RobotConfiguration(~) 
            % Ligação ao EV3 por USB
            ev3 = legoev3('usb');
            
            % Motores (portas EV3)
            LeftMotor = motor(ev3, 'B');    % esquerdo
            RightMotor = motor(ev3, 'C');   % direito
            
            % parâmetros físicos (m)
            wheelRadius = 0.0275; 
            wheelBase   = 0.118; 
            
            % struct do robô
            robot.ev3 = ev3;
            robot.leftMotor     = LeftMotor;
            robot.rightMotor    = RightMotor;
            robot.wheelRadius   = wheelRadius;
            robot.wheelBase     = wheelBase;
            robot.Ts            = 0.05;         % período de controlo (s)
            
            % Zerar encoders
            resetRotation(LeftMotor);
            resetRotation(RightMotor);
        end

%% Encontra e devolve o waypoint mais próximo da posição atual (distância Euclidiana)
        function [bestPoint, idx] = GetClosestWaypoint(~, robotPos, waypoints)
        % robotPos: [x, y] ou [x, y, theta]
        % waypoints: matriz 4x2 [x, y] com os pontos (N, S, E, O)
            
            % Normaliza o formato de robotPos:
            robotPos = robotPos(:)';    % força linha
            robotPos = robotPos(1:2);   % ignora theta
        
            % Calcula a distância do robô a cada waypoint
            dists = vecnorm(waypoints - robotPos, 2, 2);
            
            % Encontra o índice do waypoint com menor distância
            [~, idx] = min(dists);
            
            % Coordenadas do waypoint mais proximo
            bestPoint = waypoints(idx, :);
        end

%% Planeia um caminho seguro no freeZoneMap entre dois pontos (XY em px)
%  - projeta início/alvo para célula livre se necessário
%  - bwdistgeodesic + backtracking
%  - simplifica por linha de visão
%  - força nº fixo de waypoints
        function caminhoXY = PlanSafePath(app, freeZoneMap, pontoInicioXY, pontoAlvoXY)
            % Nº de pontos intermédios desejados (entre início e fim)
            numPontosIntermedios = 3;
            totalPontosTrajeto = numPontosIntermedios + 2;  % inclui início e fim
            
            freeZoneMap = logical(freeZoneMap);
            [alturaMapa, larguraMapa] = size(freeZoneMap);
        
            % Índice da célula livre mais próxima (para "projetar” pontos proibidos)
            [~, indiceCelulaLivreMaisProxima] = bwdist(freeZoneMap);
            
            % Início XY(px) -> [linha coluna]
            inicioLC = normalizarXYparaLC(app, pontoInicioXY, alturaMapa, larguraMapa);
            if ~freeZoneMap(inicioLC(1), inicioLC(2))
                inicioLC = moverParaCelulaLivreMaisProxima(app, inicioLC, freeZoneMap, indiceCelulaLivreMaisProxima);
            end

            % Alvo XY(px) -> [linha coluna]
            alvoLC_real = normalizarXYparaLC(app, pontoAlvoXY,  alturaMapa, larguraMapa);
        
            % Alvo para planeamento (garantidamente livre)
            alvoParaPlaneamento = alvoLC_real;
            if ~freeZoneMap(alvoLC_real(1), alvoLC_real(2))
                alvoParaPlaneamento = moverParaCelulaLivreMaisProxima(app, alvoLC_real, freeZoneMap, indiceCelulaLivreMaisProxima);
            end
        
            % Distâncias dentro das células livres a partir do início
            mapaDistancias = bwdistgeodesic(freeZoneMap, inicioLC(2), inicioLC(1), 'quasi-euclidean');
            
            % Sem ligação início->alvo
            if ~isfinite(mapaDistancias(alvoParaPlaneamento(1), alvoParaPlaneamento(2)))
                error('O alvo de planeamento (livre) não é alcançável a partir do início.');
            end
            
            % Caminho discreto (LC) por backtracking no mapa de distâncias
            caminhoDiscretoLC = extrairCaminho(app, mapaDistancias, inicioLC, alvoParaPlaneamento);
        
            % Simplificar em waypoints, só mantendo segmentos com "linha de visão" livre
            caminhoWaypointsLC = simplificarPorLinhaDeVisao(app, freeZoneMap, caminhoDiscretoLC);
        
            % Forçar nº de pontos (todos livres) antes da exceção do alvo
            caminhoWaypointsLC = ajustarNumeroDePontos_SoLivres(app, freeZoneMap, caminhoWaypointsLC, totalPontosTrajeto, indiceCelulaLivreMaisProxima);
          
            % Se o corte mexeu no nº de pontos, reajusta mantendo o alvo proibido
            caminhoWaypointsLC = ajustarNumeroDePontos_MantendoAlvoProibido(app, freeZoneMap, caminhoWaypointsLC, totalPontosTrajeto, indiceCelulaLivreMaisProxima);
            
            % Último ponto: alvo real se for livre, senão alvo livre (planeamento)
            if freeZoneMap(alvoLC_real(1), alvoLC_real(2))
                caminhoWaypointsLC(end,:) = alvoLC_real;
            else
                caminhoWaypointsLC(end,:) = alvoParaPlaneamento;
            end
            
            % Ajuste final normal (sem "alvo proibido”)
            caminhoWaypointsLC = ajustarNumeroDePontos_SoLivres( ...
                app, freeZoneMap, caminhoWaypointsLC, totalPontosTrajeto, indiceCelulaLivreMaisProxima);
        
            % [linha,coluna] -> XY(px)
            caminhoXY = [caminhoWaypointsLC(:,2), caminhoWaypointsLC(:,1)];
        end
        
% Se a célula for proibida, projeta para a célula livre mais próxima
        function alvoReal = moverParaCelulaLivreMaisProxima(~, alvoReal, mapaZonaLivre, indiceCelulaLivreMaisProxima)
            if mapaZonaLivre(alvoReal(1), alvoReal(2))
                return; 
            end
            
            % Move [linha coluna] para a célula livre mais próxima
            indiceProximo = indiceCelulaLivreMaisProxima(alvoReal(1), alvoReal(2));
            [linha, coluna] = ind2sub(size(mapaZonaLivre), indiceProximo);
            alvoReal = [linha, coluna];
        end

% Reconstrói o caminho (LC) por backtracking no mapa de distâncias (alvo -> início)
        function caminhoReal = extrairCaminho(~, mapaDistancias, inicioLC, alvoReal)
            [alturaMapa, larguraMapa] = size(mapaDistancias);
            atualLC = alvoReal;
            caminhoReal = atualLC;
        
            % backtracking no mapaDistancias: alvo -> início
            while ~(atualLC(1)==inicioLC(1) && atualLC(2)==inicioLC(2))
                linha = atualLC(1); coluna = atualLC(2);
        
                melhorLC = atualLC;
                melhorValor = mapaDistancias(linha, coluna);
        
                for dLinha = -1:1
                    for dColuna = -1:1
                        if dLinha==0 && dColuna==0 
                            continue; 
                        end

                        novaLinha = linha + dLinha;
                        novaColuna = coluna + dColuna;
        
                        if novaLinha<1 || novaLinha>alturaMapa || novaColuna<1 || novaColuna>larguraMapa
                            continue;
                        end
        
                        valor = mapaDistancias(novaLinha, novaColuna);
                        if isfinite(valor) && valor < melhorValor
                            melhorValor = valor;
                            melhorLC = [novaLinha, novaColuna];
                        end
                    end
                end
        
                if isequal(melhorLC, atualLC)
                    error('Backtracking bloqueado: não existe vizinho com distância menor.');
                end
                atualLC = melhorLC;
                caminhoReal(end+1,:) = atualLC;
            end
            caminhoReal = flipud(caminhoReal); % início -> alvo
        end

% Reduz o caminho discreto mantendo apenas pontos com LoS (segmento livre)
        function caminhoWaypointsLC = simplificarPorLinhaDeVisao(app, mapaZonaLivre, caminhoDiscretoLC)
            n = size(caminhoDiscretoLC,1);
            caminhoWaypointsLC = caminhoDiscretoLC(1,:);
            i = 1;
        
            while i < n
                melhorJ = i+1;
                for j = n:-1:(i+1)
                    if segmentoTotalmenteLivre(app, mapaZonaLivre, caminhoDiscretoLC(i,:), caminhoDiscretoLC(j,:))
                        melhorJ = j;
                        break;
                    end
                end
                caminhoWaypointsLC(end+1,:) = caminhoDiscretoLC(melhorJ,:); %#ok<AGROW>
                i = melhorJ;
            end
            caminhoWaypointsLC = removerRepetidosConsecutivos(app, caminhoWaypointsLC);
        end

% Testa se todas as células do segmento entre 2 pontos são livres
        function ok = segmentoTotalmenteLivre(app, mapaZonaLivre, ponto1LC, ponto2LC)
            celulasDoSegmento = listarCelulasDoSegmento(app, ponto1LC, ponto2LC);
            indices = sub2ind(size(mapaZonaLivre), celulasDoSegmento(:,1), celulasDoSegmento(:,2));
            ok = all(mapaZonaLivre(indices));
        end

% Testa se todas as células do segmento entre 2 pontos são livres, mas permite a última célula ser proibida  
        function ok = segmentoLivreExcetoUltimaCelula(app, mapaZonaLivre, ponto1LC, ponto2LC)
            celulasDoSegmento = listarCelulasDoSegmento(app, ponto1LC, ponto2LC);
            
            if size(celulasDoSegmento,1) <= 1
                ok = true; 
                return;
            end

            % Todas menos a última têm de ser livres
            indices = sub2ind(size(mapaZonaLivre), celulasDoSegmento(1:end-1,1), celulasDoSegmento(1:end-1,2));
            ok = all(mapaZonaLivre(indices));
        end

% Lista as células (LC) atravessadas por um segmento entre 2 pontos
        function celulasLC = listarCelulasDoSegmento(app, ponto1LC, ponto2LC)
            linha1 = ponto1LC(1); coluna1 = ponto1LC(2);
            linha2 = ponto2LC(1); coluna2 = ponto2LC(2);
        
            n = max(abs(linha2-linha1), abs(coluna2-coluna1)) + 1;
        
            linhas = round(linspace(linha1, linha2, n)).';
            colunas = round(linspace(coluna1, coluna2, n)).';
        
            celulasLC = removerRepetidosConsecutivos(app, [linhas, colunas]);
        end

% Remove pontos repetidos seguidos (evita segmentos nulos)
        function A = removerRepetidosConsecutivos(~, A)
            if isempty(A)
                return; 
            end
            
            manter = [true; any(diff(A,1,1)~=0,2)];
            A = A(manter,:);
        end

% Força um nº fixo de waypoints inserindo/removendo mantendo segmentos livres
        function caminhoWaypointsLC = ajustarNumeroDePontos_SoLivres(app, mapaZonaLivre, caminhoWaypointsLC, totalPontosDesejado, indiceCelulaLivreMaisProxima)
            caminhoWaypointsLC = removerRepetidosConsecutivos(app, caminhoWaypointsLC);
        
            % Remover pontos (se der) sem quebrar colisão
            while size(caminhoWaypointsLC,1) > totalPontosDesejado
                removido = false;
                for k = 2:size(caminhoWaypointsLC,1)-1
                    if segmentoTotalmenteLivre(app, mapaZonaLivre, caminhoWaypointsLC(k-1,:), caminhoWaypointsLC(k+1,:))
                        caminhoWaypointsLC(k,:) = [];
                        removido = true;
                        break;
                    end
                end
                
                if ~removido 
                    break; 
                end
            end
        
            % Inserir pontos (dividir segmentos longos)
            while size(caminhoWaypointsLC,1) < totalPontosDesejado
                m = size(caminhoWaypointsLC,1);
                comprimentos = hypot(diff(caminhoWaypointsLC(:,2)), diff(caminhoWaypointsLC(:,1)));
                [~, kMax] = max(comprimentos);
        
                celulas = listarCelulasDoSegmento(app, caminhoWaypointsLC(kMax,:), caminhoWaypointsLC(kMax+1,:));
                pontoMeio = celulas(round(size(celulas,1)/2),:);
                pontoMeio = moverParaCelulaLivreMaisProxima(app, pontoMeio, mapaZonaLivre, indiceCelulaLivreMaisProxima);
        
                caminhoWaypointsLC = [caminhoWaypointsLC(1:kMax,:); pontoMeio; caminhoWaypointsLC(kMax+1:end,:)];
                caminhoWaypointsLC = removerRepetidosConsecutivos(app, caminhoWaypointsLC);
            end
        end

% Ajusta nº de pontos mantendo o último ponto intacto (alvo pode ser proibido)
        function caminhoWaypointsLC = ajustarNumeroDePontos_MantendoAlvoProibido(app, mapaZonaLivre, caminhoWaypointsLC, totalPontosDesejado, indiceCelulaLivreMaisProxima)
            alvoLC = caminhoWaypointsLC(end,:);
            corpoLC = caminhoWaypointsLC(1:end-1,:);
        
            corpoLC = ajustarNumeroDePontos_SoLivres(app, mapaZonaLivre, corpoLC, totalPontosDesejado-1, indiceCelulaLivreMaisProxima);
        
            caminhoWaypointsLC = [corpoLC; alvoLC];
        
            % Valida último segmento com exceção
            if ~segmentoLivreExcetoUltimaCelula(app, mapaZonaLivre, caminhoWaypointsLC(end-1,:), caminhoWaypointsLC(end,:))
                warning('Após ajuste, o último segmento para o alvo proibido ficou inválido.');
            end
        end

% Converte XY (px) para [linha coluna] com clipping aos limites do mapa
        function lc = normalizarXYparaLC(~, pontoXY, alturaMapa, larguraMapa)
            % pontoXY = [x y]
            if numel(pontoXY) ~= 2 || any(~isfinite(pontoXY))
                error('Ponto XY inválido (vazio/NaN/Inf).');
            end
        
            x = round(pontoXY(1));
            y = round(pontoXY(2));
        
            % Limitar ao mapa
            x = min(max(x, 1), larguraMapa);
            y = min(max(y, 1), alturaMapa);
        
            % Para indexar matriz: [linha, coluna] = [y, x]
            lc = [y, x];
        end

%% Percorre uma lista de waypoints [x y] (m), chamando DriveToPoint em sequência
        function [finalPose, state] = FollowWaypoints(app, robot, wps, theta0, state, viz)
        % wps: Nx2 (m) no referencial da odometria
        % theta0: orientação inicial (rad) usada na inicialização/drive
            
            logFcn = @(s) app.Log(s);
        
            % Posição inicial (m, m, rad)
            finalPose = [state.x;state.y;state.th];
        
            % Ciclo para cada waypoint
            for i = 1:size(wps,1)
        
                % Seleciona o waypoint atual como alvo
                goal = wps(i,:);
                
                % Drive-to-point mantendo estado de odometria
                [finalPose, state] = DriveToPointRealPoint(app, robot, goal, state, viz, theta0);
                pause(0.5);     % margem para estabilizar antes do próximo alvo
                
                % Print do waypoint alcançado
                logFcn(sprintf("Cheguei ao WP %d: x=%.3f y=%.3f th=%.1f deg\n",i, finalPose(1), finalPose(2), finalPose(3)*180/pi));
             end
        end

%% Controlador drive-to-point (diferencial) para um alvo [x y] em metros
        function [finalPose, state] = DriveToPointRealPoint(app, robot, goal, state, viz, theta0, rotationOnly)
            logFcn = @(s) app.Log(s);

            % Parâmetros de controlo
            maxMotorSpeed     = 25;         % deg/s
            speedRampLimit    = 8;          % deg/s por ciclo (suavização/rampa)
            minMotorSpeedFar  = 12;         % deg/s mínimo quando longe do alvo
            slowDownDistance  = 0.04;       % m - distância para começar a desacelerar
            minVelocityNear   = 0.01;       % m/s - velocidade mínima quando perto
            goalTolerance     = 0.02;       % m - tolerância para considerar que chegou
            angleTolerance    = 4 * pi/180; % rad - tolerância angular para rotationOnly
            
            Kh = 0.5;  % ganho proporcional angular
            Kv = 2.0;  % ganho proporcional linear
        
            % Lógica de argumentos opcionais
            if nargin < 7
                rotationOnly = false;
            end
            if nargin < 6
                theta0 = 0; 
            end
        
            % Histórico de comandos (para suavização)
            previousCmdLeft = 0;
            previousCmdRight = 0;
        
            % Conversões para limites em v e w
            maxWheelOmega = maxMotorSpeed * pi/180;             % rad/s
            maxLinearVel = maxWheelOmega * robot.wheelRadius;   % m/s
            maxAngularVel = (2 * maxWheelOmega * robot.wheelRadius) / robot.wheelBase;  % rad/s
        
            % Inicializa odometria
            [pose, state] = GetRobotPoseFromOdometry(app, robot.leftMotor, robot.rightMotor, ...
                                                     robot.wheelRadius, robot.wheelBase, state, theta0);
        
            % Garante que motores estão a correr
            start(robot.leftMotor);
            start(robot.rightMotor);
        
            % LOOP DE CONTROLO
            while true
                % Atualiza posição pela odometria
                [pose, state] = GetRobotPoseFromOdometry(app, robot.leftMotor, robot.rightMotor, ...
                                                         robot.wheelRadius, robot.wheelBase, state, theta0);
        
                % Atualizar visualização
                if ~isempty(viz)
                    VizUpdateRealPose(app, viz, pose);
                end
        
                % Erro de posição
                deltaX = goal(1) - pose(1);
                deltaY = goal(2) - pose(2);
                distanceToGoal = sqrt(deltaX*deltaX + deltaY*deltaY);
        
                % Erro angular (rad) normalizado [-pi, pi]
                desiredHeading = atan2(deltaY, deltaX);
                headingError = mod(desiredHeading - pose(3) + pi, 2*pi) - pi;
        
                % Condição de paragem
                if rotationOnly
                    if abs(headingError) < angleTolerance
                        break;
                    end
                else
                    if distanceToGoal < goalTolerance
                        break;
                    end
                end
        
                % Controlo da velocidade linear v (m/s)
                if rotationOnly
                    linearVelocity = 0;
                else
                    linearVelocity = min(Kv * distanceToGoal, maxLinearVel);
                    
                    % Rampa de desaceleração perto do alvo
                    if distanceToGoal < slowDownDistance
                        linearVelocity = minVelocityNear + (linearVelocity - minVelocityNear) * (distanceToGoal / slowDownDistance);
                    end
        
                    % Lógica turn-only com histerese
                    turnOnlyThresholdHigh = 0.35; 
                    turnOnlyThresholdLow  = 0.15; 
                    if ~isfield(state, 'turnOnly')
                        state.turnOnly = false; 
                    end
        
                    if state.turnOnly
                        if abs(headingError) < turnOnlyThresholdLow
                            state.turnOnly = false;
                            if isfield(state, 'turnOnlyStartTime')
                                state = rmfield(state, 'turnOnlyStartTime'); 
                            end
                        else
                            % Timeout para turn-only
                            if ~isfield(state, 'turnOnlyStartTime')
                                state.turnOnlyStartTime = tic;
                            elseif toc(state.turnOnlyStartTime) > 3.0
                                state.turnOnly = false;
                                state = rmfield(state, 'turnOnlyStartTime');
                                logFcn(sprintf('Turn-only timeout - retomando movimento\n'));
                            end
                        end
                    else
                        if abs(headingError) > turnOnlyThresholdHigh
                            state.turnOnly = true;
                        end
                    end
                    
                    % Aplica turn-only / escala por erro angular
                    if state.turnOnly
                        linearVelocity = 0;
                    else
                        angleScale = max(0, 1 - abs(headingError) / turnOnlyThresholdHigh);
                        linearVelocity = linearVelocity * angleScale;
                    end
                end
        
                % Zona morta angular
                if abs(headingError) < 0.03
                    headingError = 0;
                end
        
                % Controlo da  velocidade angular w (rad/s)
                slowDownAngle = 20 * pi/180; 
                angularVelocity = Kh * headingError;
                
                % Se o erro for pequeno, reduzir a velocidade angular drasticamente para evitar overshoot
                if abs(headingError) < slowDownAngle
                    minAngularVel = 0.05;           % Velocidade mínima para garantir movimento
                    angularVelocity = sign(headingError) * max(minAngularVel, abs(angularVelocity) * (abs(headingError) / slowDownAngle));
                end
                
                angularVelocity = min(max(angularVelocity, -maxAngularVel), maxAngularVel);
        
                % Cinemática inversa 
                [wheelVelLeft, wheelVelRight] = inverseKinematics(app, linearVelocity, angularVelocity);
        
                % Conversão de rad/s -> deg/s
                cmdLeft = wheelVelLeft * (180/pi);
                cmdRight = wheelVelRight * (180/pi);
        
                % Velocidade mínima longe do alvo
                if ~rotationOnly && distanceToGoal > slowDownDistance
                    maxCmd = max(abs(cmdLeft), abs(cmdRight));
                    if maxCmd > 0 && maxCmd < minMotorSpeedFar
                        scaleFactor = minMotorSpeedFar / (maxCmd + eps);
                        cmdLeft = cmdLeft * scaleFactor;
                        cmdRight = cmdRight * scaleFactor;
                    end
                end
        
                % Velocidade mínima  em rotação (vencer atrito)
                minMotorSpeedTurn = 10;         % deg/s (ajusta 8–15)
                if rotationOnly
                    maxCmd = max(abs(cmdLeft), abs(cmdRight));
                    if maxCmd > 0 && maxCmd < minMotorSpeedTurn
                        scale = minMotorSpeedTurn/(maxCmd + eps);
                        cmdLeft  = cmdLeft  * scale;
                        cmdRight = cmdRight * scale;
                    end
                end
    
                % Saturação 
                cmdLeft = min(max(cmdLeft, -maxMotorSpeed), maxMotorSpeed);
                cmdRight = min(max(cmdRight, -maxMotorSpeed), maxMotorSpeed);
                
                % Rampa de Velocidade
                deltaLeft = cmdLeft - previousCmdLeft;
                deltaRight = cmdRight - previousCmdRight;
                maxDelta = max(abs(deltaLeft), abs(deltaRight));
                if maxDelta > speedRampLimit
                    scale = speedRampLimit / maxDelta;
                    cmdLeft = previousCmdLeft + deltaLeft * scale;
                    cmdRight = previousCmdRight + deltaRight * scale;
                end
                previousCmdLeft = cmdLeft;
                previousCmdRight = cmdRight;
        
                % Envia comandos
                robot.leftMotor.Speed = cmdLeft;
                robot.rightMotor.Speed = cmdRight;
                
                pause(robot.Ts);
            end
            
            % Parar motores
            stop(robot.leftMotor, 1);
            stop(robot.rightMotor, 1);
            
            finalPose = pose;
        end

%% Detetar sinal e rodar +30º e -30º
        function [sinalFinal, finalPose, state] = DetetarSinalComRotacao(app, robot, state, viz, currentPose, varargin)
            logFcn = @(s) app.Log(s);
            logFcn(sprintf('\n=== Deteção de Sinal ===\n'));
            
            % Ligar câmara uma vez (reutiliza nas 3 tentativas)
            camara = LigarCamera(app,app.IPEditField_2.Value, app.UserEditField_2.Value, app.PasswordEditField_2.Value);
            if isempty(camara)
                return; 
            end
            
            % warm-up snapshots
            for i = 1:3
                snapshot(camara); 
                pause(0.2); 
            end
            
            % Posição inicial (odometria)
            poseInicial = [state.x; state.y; state.th];
            finalPose   = poseInicial;
            
            % Tentativa 1: sem rodar
            [sinal, confirmado] = TentarLerEConfirmarSinal(app,camara);
            if confirmado
                sinalFinal = sinal;
                finalPose = currentPose;
                delete(camara);
                return;
            end
            
            % Tentativa 2: rodar +20º (esquerda) e ler sinal
            logFcn(sprintf('\nTentativa 2: Rodar +20º (esquerda)...\n'));
            targetTheta = poseInicial(3) + deg2rad(20);
            targetTheta = atan2(sin(targetTheta), cos(targetTheta));
            logFcn(sprintf('  Theta alvo: %.1fº\n', rad2deg(targetTheta)));
            
            % Alvo fictício à frente (rotationOnly=true -> só usa direção)
            targetPoint = poseInicial(1:2) + 1.0 * [cos(targetTheta); sin(targetTheta)];
            [~, state] = DriveToPointRealPoint(app, robot, targetPoint, state, viz, poseInicial(3), true);
            
            finalPose = [state.x; state.y; state.th];
            deltaTheta = atan2(sin(finalPose(3) - poseInicial(3)), cos(finalPose(3) - poseInicial(3)));
            logFcn(sprintf('  Rotação real: %.1fº\n', rad2deg(deltaTheta)));
            pause(0.5);
            
            [sinal, confirmado] = TentarLerEConfirmarSinal(app,camara);
            if confirmado
                sinalFinal = sinal;
                delete(camara); 
                return;
            end
            
            % Tentativa 3: voltar ao theta inicial e rodar -20º (direita) e ler
            logFcn(sprintf('\nTentativa 3: Rodar para -20º (direita, relativamente à posição inicial)...\n'));
            
            logFcn(sprintf('  Passo 3a: Voltar à orientação inicial (%.1fº)...\n', rad2deg(poseInicial(3))));
            targetPoint = poseInicial(1:2) + 1.0 * [cos(poseInicial(3)); sin(poseInicial(3))];
            [~, state] = DriveToPointRealPoint(app, robot, targetPoint, state, viz, finalPose(3), true);
            
            finalPose = [state.x; state.y; state.th];
            logFcn(sprintf('  Voltou para: %.1fº\n', rad2deg(finalPose(3))));
            pause(0.3);

            logFcn(sprintf('  Passo 3b: Rodar -20º a partir da inicial...\n'));
            targetTheta = poseInicial(3) + deg2rad(-20);
            targetTheta = atan2(sin(targetTheta), cos(targetTheta));
            logFcn(sprintf('  Theta alvo: %.1fº\n', rad2deg(targetTheta)));

            targetPoint = poseInicial(1:2) + 1.0 * [cos(targetTheta); sin(targetTheta)];
            [finalPose, state] = DriveToPointRealPoint(app, robot, targetPoint, state, viz, finalPose(3), true);

            deltaTheta = atan2(sin(finalPose(3) - poseInicial(3)), cos(finalPose(3) - poseInicial(3)));
            logFcn(sprintf('  Rotação desde inicial: %.1fº\n', rad2deg(deltaTheta)));

            pause(0.5);
            [sinal, confirmado] = TentarLerEConfirmarSinal(app,camara);
            if confirmado
                sinalFinal = sinal;
                delete(camara); 
                return;
            end
            
            % Fecha camara
            delete(camara);

            logFcn(sprintf('Nenhum sinal detetado após 3 tentativas.\n'));
            sinalFinal = 'NONE';
        end
        
%% Lê e verifica o sinal da imagem
        function [sinal, confirmado] = TentarLerEConfirmarSinal(app, camera)
            pause(2.0);
            frame = snapshot(camera);
            sinal = LerSinal(app, frame);
            confirmado = false;
            
            while ~confirmado
                % Se detetou algum sinal, pede confirmação
                if ~strcmpi(sinal, 'NONE')
                    fprintf('\n>>> SINAL DETETADO: %s <<<\n', sinal);
                    fprintf('Este sinal está correto? (s/n) [ENTER = sim]: ');
                    resposta = lower(strtrim(input('', 's')));
                    
                    % ENTER ou "sim" confirma o sinal
                    if isempty(resposta) || any(strcmp(resposta, {'s','sim','y','yes'}))
                        confirmado = true;
                        fprintf('Sinal confirmado: %s\n\n', sinal);
                        continue;
                    end
                end
                
                % Se não confirmou ou se não detetou nada, pede correção
                fprintf('Qual é o sinal correto? (STOP/ESQUERDA/DIREITA/NONE): ');
                sinalCorreto = upper(strtrim(input('', 's')));
                
                if ismember(sinalCorreto, {'STOP', 'ESQUERDA', 'DIREITA', 'NONE'})
                    sinal = sinalCorreto;
                    confirmado = true;
                    fprintf('Sinal corrigido para: %s\n\n', sinal);
                end
            end
        end

%% Define uma trajetória de contorno completo à volta de uma base
        function [traj_m, idxFinal] = DefineRoute(~, idxChegada, sinal, cantos_m)
            % cantos_m: 1=NW, 2=NE, 3=SW, 4=SE
            NW = cantos_m(1,:); NE = cantos_m(2,:); 
            SW = cantos_m(3,:); SE = cantos_m(4,:);
        
            if strcmpi(sinal, 'DIREITA') % Sentido Anti-Horário (Ex: N -> NW -> SW -> SE -> NE)
                % Cada sequência agora tem 4 pontos para completar o contorno
                transicoes = { 
                    [NW; SW; SE; NE], ... % Chegou pelo Norte (1)
                    [SE; NE; NW; SW], ... % Chegou pelo Sul (2)
                    [SW; SE; NE; NW], ... % Chegou pelo Oeste (3)
                    [NE; NW; SW; SE]      % Chegou pelo Este (4)
                };
            else % Sentido Horário (Ex: N -> NE -> SE -> SW -> NW)
                transicoes = { 
                    [NE; SE; SW; NW], ... % Chegou pelo Norte (1)
                    [SW; NW; NE; SE], ... % Chegou pelo Sul (2)
                    [NW; NE; SE; SW], ... % Chegou pelo Oeste (3)
                    [SE; SW; NW; NE]      % Chegou pelo Este (4)
                };
            end
        
            traj_m = transicoes{idxChegada};

            % O idxFinal agora é o mesmo da chegada, pois completamos a volta
            idxFinal = idxChegada; 
        end

%% Converte um ponto em metros (no referencial da odometria) para coordenadas em pixels na imagem
        function p_px = m2px(~, p_m, origin_px, mPerPix)
        % p_m: Nx2 [x y] em metros
        % origin_px: [x0 y0] em pixels
        % mPerPix: metros por pixel
            
            p_m = double(p_m);
            origin_px = double(origin_px(:).');
            
            % Converte X
            x = origin_px(1) + p_m(1)/mPerPix;
            
            % Converte Y:
            % no mundo, +Y costuma ser "para cima"; na imagem, +Y é "para baixo"
            % por isso aqui subtrai para inverter o eixo vertical
            y = origin_px(2) - p_m(2)/mPerPix;
            
            % Devolve coordenadas em pixels [x y]
            p_px = [x, y];
        end

%% Converte um ponto em pixeis para metros
        function p_m = px2m(~, p_px, origin_px, mPerPix)
        % p_px: Nx2 [x y] em pixeis
        % origin_px: [x0 y0] em pixels
        % mPerPix: metros por pixel
        
            p_px = double(p_px);
            origin_px = double(origin_px(:).');
            
            % Converte X
            dx = (p_px(:,1) - origin_px(1)) * mPerPix;
            
            % Converte Y:
            % no mundo, +Y costuma ser "para cima"; na imagem, +Y é "para baixo"
            % por isso aqui subtrai para inverter o eixo vertical
            dy = -(p_px(:,2) - origin_px(2)) * mPerPix;
            
            % Devolve coordenadas em metros [x y]
            p_m = [dx, dy];
        end

%% Estima a posição [x;y;theta] do robô por odometria diferencial a partir dos encoders
        function [pose, state] = GetRobotPoseFromOdometry(app, LeftMotor, RightMotor, wheelRadius, wheelBase, state, theta0)
            % Lê encoders (graus)
            degL = double(readRotation(LeftMotor));
            degR = double(readRotation(RightMotor));
        
            % Inicialização
            if nargin < 5 || isempty(state) || ~isstruct(state) || ~isfield(state,'x')
                state.x = 0;
                state.y = 0;
                state.th = theta0;
        
                state.lastDegL = degL;
                state.lastDegR = degR;
        
                pose = [state.x; state.y; state.th];
                return;
            end
        
            % Incrementos desde a última iteração (graus)
            dDegL = degL - state.lastDegL;
            dDegR = degR - state.lastDegR;
        
            % Converte para rad (incremento angular de cada roda)
            wL = dDegL * pi/180;
            wR = dDegR * pi/180;
        
            % Cinemática direta 
            %   forwardKinematics está escrito para velocidades (rad/s),
            %   mas a fórmula é linear e funciona igual para incrementos (rad):
            [ds, dth] = forwardKinematics(app, wL, wR);
            
            % Integração no plano (arco quando dth != 0)
            if abs(dth) > 1e-6
                % O robô segue um arco de raio R = ds/dth
                dx = (ds / dth) * (sin(state.th + dth) - sin(state.th));
                dy = (ds / dth) * (-cos(state.th + dth) + cos(state.th));
            else
                % Movimento retilíneo - evita divisão por zero
                dx = ds * cos(state.th);
                dy = ds * sin(state.th);
            end
            
            % Atualizar estado
            state.x = state.x + dx;
            state.y = state.y + dy;
            state.th = mod(state.th + dth + pi, 2*pi) - pi; % [-pi, pi]
        
            % Atualiza encoders guardados
            state.lastDegL = degL;
            state.lastDegR = degR;
        
            pose = [state.x; state.y; state.th];
        end

%% Ler os sinais (STOP / ESQUERDA / DIREITA) a partir de uma frame RGB
        function [sinalDetetado] = LerSinal(app, frameAtual)
            % Frame original
            imshow(frameAtual, 'Parent', app.UIAxes_Sinal_Foto_Original);
            
            % RGB -> HSV
            HSV = rgb2hsv(frameAtual);
            H = HSV(:,:,1);
            S = HSV(:,:,2);
            V = HSV(:,:,3);
        
            sinalDetetado = 'NONE';
            
            % Thresholds HSV (calibração)
            BS = getHSVthr(app,'BS'); % sinal branco
            AS = getHSVthr(app,'AS'); % sinal azul
            VS = getHSVthr(app,'VS'); % sinal vermelho         
            
            % Máscaras base
            mascaraVermelha = hsvMask(app, H,S,V, VS);
            mascaraVermelha = bwareaopen(mascaraVermelha,200);
            
            mascaraAzul = hsvMask(app, H,S,V, AS);
            mascaraAzul = bwareaopen(mascaraAzul,200);
            
            mascaraBranca = hsvMask(app, H,S,V, BS);
            
            % Contagem de pixeis
            tamanhoImagem = numel(H);
            pixeisVermelhos  = sum(mascaraVermelha(:));
            pixeisAzuis = sum(mascaraAzul(:));                
            
            % ---------------- STOP (vermelho + branco dentro) ----------------
            if pixeisVermelhos > (tamanhoImagem * 0.05)
                % Maior blob vermelho
                objetosVermelho = bwlabel(mascaraVermelha);
                caracteristicasObjetos = regionprops(objetosVermelho,'Area');
                [~, maiorObjetoVermelho] = max([caracteristicasObjetos.Area]);
                
                mascaraMaiorVermelho = (objetosVermelho == maiorObjetoVermelho);
                mascaraMaiorVermelho = imfill(mascaraMaiorVermelho, "holes");
                imshow(mascaraMaiorVermelho, 'Parent', app.UIAxes_Mascara_Vermelho);
                
                % Se tiver branco dentro do vermelho -> STOP
                brancoDentroDoVermelho = mascaraMaiorVermelho & mascaraBranca;
                if any(brancoDentroDoVermelho(:))
                    sinalDetetado = 'STOP';
                end
        
            % ---------------- SETA (azul + branco dentro) ----------------
            elseif pixeisAzuis > (tamanhoImagem * 0.10)
                % Maior blob azul
                objetosAzuis = bwlabel(mascaraAzul);
                caracterisiticasObjetos = regionprops(objetosAzuis,'Area');
                [~, maiorObjetoAzul] = max([caracterisiticasObjetos.Area]);
                
                mascaraMaiorAzul = (objetosAzuis == maiorObjetoAzul);
                mascaraAzulFechado = imclose(mascaraMaiorAzul, strel('disk',15));
                mascaraAzulPreenchido = imfill(mascaraAzulFechado,"holes");
                imshow(mascaraAzulPreenchido, 'Parent', app.UIAxes_Mascara_Azul);

                roiBlue = mascaraAzulPreenchido;

                % Branco dentro do azul (IGNORANDO H)
                BS = getHSVthr(app,'BS');
                whiteInBlue = roiBlue & (S <= BS.smax) & (V >= BS.vmin);
                
                % Maior objeto branco dentro do azul
                ccW = bwconncomp(whiteInBlue);
                if ccW.NumObjects == 0
                    sinalDetetado = 'NONE';
                    return;
                end

                areasW = cellfun(@numel, ccW.PixelIdxList);
                [~, idxMaxW] = max(areasW);
                
                whiteLargest = false(size(whiteInBlue));
                whiteLargest(ccW.PixelIdxList{idxMaxW}) = true;
                
                imshow(whiteLargest, 'Parent', app.UIAxes_Mascara_Branco);
                
                % Centroides para decidir lado da seta
                propsW = regionprops(whiteLargest,'Area','Centroid');
                propsB = regionprops(roiBlue,'Area','Centroid');
                
                if isempty(propsW) || isempty(propsB)
                    sinalDetetado = 'NONE';
                    return;
                end
                
                [~,iw] = max([propsW.Area]);
                [~,ib] = max([propsB.Area]);
                
                CentroSetaBranca = propsW(iw).Centroid(1);
                CentroSinalAzul  = propsB(ib).Centroid(1);
                
                if CentroSetaBranca > CentroSinalAzul
                    sinalDetetado = 'DIREITA';
                else
                    sinalDetetado = 'ESQUERDA';
                end
            end
        end
        
%% Escreve mensagens no LogTextArea
        function Log(app, msg)
            % Normalizar mensagem para char (1 linha)
            msg = char(string(msg));
    
            % Garantir formato cell array de linhas
            if isempty(app.LogTextArea.Value)
                app.LogTextArea.Value = {msg};
            else
                app.LogTextArea.Value = [app.LogTextArea.Value; {msg}];
            end
    
            % Limitar nº de linhas
            maxLines = 300;
            if numel(app.LogTextArea.Value) > maxLines
                app.LogTextArea.Value = app.LogTextArea.Value(end-maxLines+1:end);
            end
    
            drawnow limitrate;
        end
               
%% Calibrar cores (com ginput)
        function CalibrateColor(app, prefix, colorName, colorCode, nPoints)
            % nº de amostras
            if nargin < 5 || isempty(nPoints)
                nPoints = 5;
            end
        
            % Usa snapshot guardado
            if ~isprop(app,'lastSnapshotRGB') || isempty(app.lastSnapshotRGB)
                uialert(app.UIFigure,'Primeiro captura a foto (snapshot).','Sem imagem');
                return;
            end
            IM = app.lastSnapshotRGB;
        
            % ginput numa figure normal
            f = figure('Name', ['Calibracao - ' upper(colorName)], 'NumberTitle', 'off');
            imshow(IM);
            title(['Clique ' num2str(nPoints) ' pontos na cor ' upper(colorName) ' e pressione ENTER'], 'Color', colorCode);
        
            [x, y] = ginput(nPoints);
            close(f);
        
            % RGB -> HSV
            HSV = rgb2hsv(IM);
            H = HSV(:,:,1);
            S = HSV(:,:,2);
            V = HSV(:,:,3);
            
            % Arredondar e limitar coordenadas
            x = round(x);
            y = round(y);
        
            H_values = zeros(numel(x),1);
            S_values = zeros(numel(x),1);
            V_values = zeros(numel(x),1);
        
            for i = 1:numel(x)
                row = min(max(y(i), 1), size(IM, 1));
                col = min(max(x(i), 1), size(IM, 2));
        
                H_values(i) = H(row, col);
                S_values(i) = S(row, col);
                V_values(i) = V(row, col);
            end
        
            % Margens
            Hmin = min(H_values) - 0.08;
            Hmax = max(H_values) + 0.08;
            Smin = min(S_values) - 0.15;
            Smax = 1.0;
            Vmin = min(V_values) - 0.15;
            Vmax = 1.0;
        
            % Normalizar limites
            if Hmin < 0, Hmin = Hmin + 1.0; end
            if Hmax > 1, Hmax = Hmax - 1.0; end
            if Smin < 0, Smin = 0.0; end
            if Vmin < 0, Vmin = 0.0; end
        
            % Escrever nos NumericEditFields
            app.([prefix '_H_min']).Value = Hmin;
            app.([prefix '_H_max']).Value = Hmax;
            app.([prefix '_S_min']).Value = Smin;
            app.([prefix '_S_max']).Value = Smax;
            app.([prefix '_V_min']).Value = Vmin;
            app.([prefix '_V_max']).Value = Vmax;
        end
        
%% Lê do UI os thresholds HSV (prefix_*_min/max) e devolve numa struct
        function thr = getHSVthr(app, prefix)
            thr.hmin = app.([prefix '_H_min']).Value;
            thr.hmax = app.([prefix '_H_max']).Value;
            thr.smin = app.([prefix '_S_min']).Value;
            thr.smax = app.([prefix '_S_max']).Value;
            thr.vmin = app.([prefix '_V_min']).Value;
            thr.vmax = app.([prefix '_V_max']).Value;
        end
        
%% Aplica threshold HSV e devolve máscara binária
        function mask = hsvMask(~, H, S, V, thr)
            % Hue circular (wrap-around) — necessário para vermelho
            if thr.hmin <= thr.hmax
                mH = (H >= thr.hmin) & (H <= thr.hmax);
            else
                mH = (H >= thr.hmin) | (H <= thr.hmax);
            end
            mask = mH & (S >= thr.smin) & (S <= thr.smax) & (V >= thr.vmin) & (V <= thr.vmax);
        end

%% Cinemática direta
       function [v,w] = forwardKinematics(app,wL,wR)
            % Calculates linear and angular velocity from wheel speeds
            v = 0.5*app.robot.wheelRadius*(wL+wR);
            w = (wR-wL)*app.robot.wheelRadius/app.robot.wheelBase;
       end       

%% Cinemática inversa
        function [wL,wR] = inverseKinematics(app,v,w)
           % Calculates wheel speeds from linear and angular velocity
           wL = (v - w*app.robot.wheelBase/2)/app.robot.wheelRadius;
           wR = (v + w*app.robot.wheelBase/2)/app.robot.wheelRadius;
        end
        
%% Corrigir posição do robo
        function [posicaoFinal, state, correcaoOk] = CorrigirPosicaoAutomaticamente(app, robot, state, viz, waypointAlvo_m)
            correcaoOk = false;
            posicaoFinal = [state.x; state.y; state.th];      
            metrosPorPixel = app.mmPerPixel/1000;
            
            % Ligar câmara top-down
            camera = LigarCamera(app, app.IPEditField.Value, app.UserEditField.Value, app.PasswordEditField.Value);

            % Se não ligar a camera, retorna a posição atual do robô
            if isempty(camera)
                return;
            end

            % warm-up snapshots
            for i = 1:3  
                try 
                    snapshot(camera); 
                catch
                end
                pause(0.2);
            end
            
            % snapshot para deteção
            try
                frame = snapshot(camera);
            catch
                return;
            end
            
            % Posição do robô na imagem (px + rad)
            [ok, posicaoRobo, orientacaoRobo] = DetectRobotPoseFromFrame(app, frame);

            % Se nao houver posicao do robo ou orientacao, sai do loop
            if ~ok
                return;
            end
            
            % px -> m
            posicaoRoboMetros = px2m(app, posicaoRobo, app.origin_px, metrosPorPixel);
        
            % Atualiza a odometria com a posição do robo lida pela camera
            state.x  = posicaoRoboMetros(1);
            state.y  = posicaoRoboMetros(2);
            state.th = orientacaoRobo;
            
            % Atualizar referências no app (viz/arranque)
            app.theta0 = state.th;
            app.robotCentroid_px = m2px(app, [state.x; state.y], app.origin_px, metrosPorPixel);
            
            % Reposicionar no waypoint alvo (m)
            [~, state] = DriveToPointRealPoint(app, robot, waypointAlvo_m, state, viz, state.th, false);
            
            % Atualizar posição final no app
            app.theta0 = state.th;
            app.robotCentroid_px = m2px(app, [state.x; state.y], app.origin_px, metrosPorPixel);
            
            pause(0.5);
                
            % Validação por distância ao waypoint
            distanciaFinal = norm([state.x; state.y] - waypointAlvo_m(:).');
            correcaoOk = (distanciaFinal <= 0.01);          % Tolerância de 1cm
            posicaoFinal = [state.x; state.y; state.th];
              
        end

%% Deteta (px) o centro do robô e estima theta a partir dos marcadores (roxo/amarelo)
        function [ok, posicao_Robo, orientacaoRobo] = DetectRobotPoseFromFrame(app, frame)
            % Inicialização das variaveis e flag
            ok = false;
            posicao_Robo = [NaN NaN];
            orientacaoRobo = 0;
        
            % Se não houver frame, retorna à função que o chamou
            if isempty(frame) || ndims(frame) ~= 3
                return;
            end
            
            % RGB -> HSV
            hsvImage = rgb2hsv(frame);
            H = hsvImage(:,:,1);
            S = hsvImage(:,:,2);
            V = hsvImage(:,:,3);

            % Máscara única das bases dilatadas
            InflatedBasesMask = false(size(V));
            if isprop(app,'processedBases') && ~isempty(app.BasesCorrection)
                if iscell(app.BasesCorrection)
                    for i = 1:numel(app.BasesCorrection)
                        if ~isempty(app.BasesCorrection{i})
                            InflatedBasesMask = InflatedBasesMask | logical(app.BasesCorrection{i});
                        end
                    end
                else
                    InflatedBasesMask = logical(app.BasesCorrection);
                end
            end
        
            % Restringir ao boardMask já calculado na foto da arena (fallback se resolução não bater certo)
            if isprop(app,'boardMask') && ~isempty(app.boardMask)
                app.boardMask = app.boardMask;
                if ~isequal(size(app.boardMask,1), size(H,1)) || ~isequal(size(app.boardMask,2), size(H,2))
                    app.boardMask = true(size(H));
                end
            else
                app.boardMask = true(size(H));
            end
        
            % thresholds dos marcadores do robô (lilás=LM, amarelo=AM)
            LilasMask = getHSVthr(app,'LM');
            AmareloMask = getHSVthr(app,'AM');

            % Mascara do Robo Preta
            mascaux = app.freeZoneMap | InflatedBasesMask;
            blackMaskRobo = (V < 0.35) & mascaux;
            blackMaskRobo = bwareaopen(blackMaskRobo,800);
            blackMaskRobot = imclose(blackMaskRobo, strel('disk', 15));
            blackMaskFilled = bwareaopen(imfill(blackMaskRobot,"holes"), 200);
            figure;subplot(2,2,1);imshow(blackMaskFilled), title('preto');
            
            % Mascara do Centro das Rodas
            purpleCand = hsvMask(app, H,S,V, LilasMask) & blackMaskFilled;
            purpleMask = bwareaopen(purpleCand, 30);
            purpleMask = imclose(purpleMask, strel('disk',20));
            purpleMask = imfill(purpleMask, "holes");
            subplot(2,2,2);imshow(purpleMask), title('roxo');
        
            % Mascara do Amarelo para calcular orientação
            yellowCand = hsvMask(app, H,S,V, AmareloMask) & blackMaskFilled;
            yellowMask = bwareaopen(yellowCand, 30);
            yellowMask = imclose(yellowMask, strel('disk',20));
            yellowMask = imfill(yellowMask, "holes");
            subplot(2,2,3);imshow(yellowMask), title('amarelo');
        
            % Caracteristicas das mascaras lilás e amarelo
            propsLilas = regionprops(purpleMask, 'Centroid','Area');
            propsAmarelo = regionprops(yellowMask, 'Centroid','Area');
            if isempty(propsLilas) || isempty(propsAmarelo)
                return;
            end
        
            % Maior Lilás e Amarelo
            [~, indiceLilas] = max([propsLilas.Area]);
            [~, indiceAmarelo] = max([propsAmarelo.Area]);
        
            % Centroide do Maior Lilás e Amarelo
            centroidLilas = propsLilas(indiceLilas).Centroid;
            centroidAmarelo = propsAmarelo(indiceAmarelo).Centroid;
            
            % Posição do robô (px) = marcador roxo
            posicao_Robo = centroidLilas;
        
            % Calculo o angulo do robô
            distanciaEmX = centroidLilas(1) - centroidAmarelo(1);
            distanciaEmY = -(centroidLilas(2) - centroidAmarelo(2));
            orientacaoRobo = atan2(distanciaEmY, distanciaEmX);
        
            % Caso a orientação do robo anterior ultrapasse -pi ou pi, é normalizado
            orientacaoRobo = atan2(sin(orientacaoRobo), cos(orientacaoRobo)); 
        
            % Flag para dizer à função que o chama que os valores necessários estão calculados
            ok = all(isfinite(posicao_Robo)) && isfinite(orientacaoRobo);
        end
        
%% Dar reset à odometria
        function state = ResetOdometryAtBase(app,state, basePosition_m, baseIdx)
            logFcn = @(s) app.Log(s);
            
            if nargin < 3
                baseIdx = NaN;
            end
            
            % Guardar posição anterior (log)
            old_x = state.x;
            old_y = state.y;
            
            % Reset de posição para a base (m)
            state.x = basePosition_m(1);
            state.y = basePosition_m(2);
            
            % Delta aplicado (log)
            correction_x = state.x - old_x;
            correction_y = state.y - old_y;
            correction_dist = sqrt(correction_x^2 + correction_y^2);
            
            if isfinite(baseIdx)
                logFcn(sprintf('\n=== ODOMETRY RESET AT BASE %d ===\n', baseIdx));
            else
                logFcn(sprintf('\n=== ODOMETRY RESET ===\n'));
            end
            logFcn(sprintf('  Old position: (%.3f, %.3f)\n', old_x, old_y));
            logFcn(sprintf('  New position: (%.3f, %.3f)\n', state.x, state.y));
            logFcn(sprintf('  Correction:   (%.3f, %.3f) - Distance: %.3f m (%.1f cm)\n', ...
                    correction_x, correction_y, correction_dist, correction_dist*100));
            logFcn(sprintf('  Heading kept: %.1f°\n\n', rad2deg(state.th)));
            
            % Limpar flags do turn-only para novo segmento
            if isfield(state, 'turnOnly')
                state = rmfield(state, 'turnOnly');
            end
            if isfield(state, 'turnOnlyStartTime')
                state = rmfield(state, 'turnOnlyStartTime');
            end
        end

%%  Roda no sítio até ficar orientado para um ponto alvo (m)
        function [finalPose, state] = RotateToFacePoint(app, robot, targetPoint_m, state, viz)
            % Parâmetros
            maxMotorSpeed   = 20;           % deg/s
            speedRampLimit  = 8;            % deg/s por ciclo
            angleTolerance  = 2*pi/180;     % rad
            Kh              = 0.5;          % ganho angular
            deadband        = 0.02;         % rad 
            minMotorSpeedTurn = 10;         % deg/s 
            slowDownAngle   = 10*pi/180;    % rad
        
            % Limites derivados (w -> rad/s)
            maxWheelOmega  = maxMotorSpeed * pi/180;
            maxAngularVel  = (2 * maxWheelOmega * robot.wheelRadius) / robot.wheelBase;
        
            % Garantir campos no state
            if ~isfield(state,'turnOnly') 
                state.turnOnly = false; 
            end
        
            % Sincronizar encoders com leitura atual
            state.lastDegL = double(readRotation(robot.leftMotor));
            state.lastDegR = double(readRotation(robot.rightMotor));
        
            % Garante motores ativos
            start(robot.leftMotor);
            start(robot.rightMotor);
        
            prevCmdL = 0; 
            prevCmdR = 0;
        
            % Loop de rotação
            while true
                % Atualiza posição (odometria)
                [pose, state] = GetRobotPoseFromOdometry(app, robot.leftMotor, robot.rightMotor, ...
                                                         robot.wheelRadius, robot.wheelBase, state, state.th);
        
                % Vizualizar
                if ~isempty(viz)
                    VizUpdateRealPose(app, viz, pose);
                end
        
                % Heading ao alvo e erro [-pi, pi]
                dx = targetPoint_m(1) - pose(1);
                dy = targetPoint_m(2) - pose(2);
                desiredHeading = atan2(dy, dx);
                headingError = mod(desiredHeading - pose(3) + pi, 2*pi) - pi;
        
                % Condição de paragem
                if abs(headingError) < angleTolerance
                    break;
                end
        
                % Zona morta
                if abs(headingError) < deadband
                    headingError = 0;
                end
        
                % Controlo angular (w)
                angularVelocity = Kh * headingError;
        
                % Desaceleração perto do alvo (evitar overshoot)
                if abs(headingError) < slowDownAngle
                    minW = 0.05;        % rad/s
                    angularVelocity = sign(headingError) * max(minW, abs(angularVelocity) * (abs(headingError)/slowDownAngle));
                end
        
                % Saturação (rad/s)
                angularVelocity = min(max(angularVelocity, -maxAngularVel), maxAngularVel);
        
                % Rotação pura
                linearVelocity = 0;
        
                % Cinemática inversa -> velocidades rodas (rad/s)
                [wL, wR] = inverseKinematics(app, linearVelocity, angularVelocity);
        
                % rad/s -> deg/s
                cmdL = wL * (180/pi);
                cmdR = wR * (180/pi);
        
                % Mínimo para vencer atrito na rotação
                maxCmd = max(abs(cmdL), abs(cmdR));
                if maxCmd > 0 && maxCmd < minMotorSpeedTurn
                    s = minMotorSpeedTurn/(maxCmd + eps);
                    cmdL = cmdL * s;
                    cmdR = cmdR * s;
                end
        
                % Saturação deg/s
                cmdL = min(max(cmdL, -maxMotorSpeed), maxMotorSpeed);
                cmdR = min(max(cmdR, -maxMotorSpeed), maxMotorSpeed);
        
                % Rampa
                dL = cmdL - prevCmdL;
                dR = cmdR - prevCmdR;
                md = max(abs(dL), abs(dR));
                if md > speedRampLimit
                    sc = speedRampLimit/md;
                    cmdL = prevCmdL + dL*sc;
                    cmdR = prevCmdR + dR*sc;
                end
                prevCmdL = cmdL;
                prevCmdR = cmdR;
        
                % Comando motores
                robot.leftMotor.Speed  = cmdL;
                robot.rightMotor.Speed = cmdR;
        
                pause(robot.Ts);
            end
            
            % Para motores
            stop(robot.leftMotor, 1);
            stop(robot.rightMotor, 1);
        
            finalPose = pose;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartLiveButton
        function StartLiveButtonPushed(app, event)
            % Flag do loop live
            app.live = true;
            
            % Bloquear botões durante live
            app.StartLiveButton.Enable = "off";
            app.SelecionarfotoButton.Enable = "off";
            app.ProcessarimagemdomapaButton.Enable = "off";
            
            app.StopLiveButton.Enable = "on";
            app.CapturarfotoButton.Enable = "on";

            app.CalibrarARENAButton.Enable = "off";
            app.CalibrarBASE1Button.Enable = "off";
            app.CalibrarBASE2Button.Enable = "off";
            app.CalibrarBASE3Button.Enable = "off";
            app.CalibrarBASE4Button.Enable = "off";
            app.CalibrarVERMELHOButton.Enable = "off";
            app.CalibrarAZULButton.Enable = "off";
            app.CalibrarBrancoButton.Enable = "off";
            
            % Ligar câmara
            app.video = LigarCamera(app, app.IPEditField.Value, app.UserEditField.Value, app.PasswordEditField.Value);
        
            % Frame inicial
            try
                frame = snapshot(app.video);
            catch
                app.live = false;
                uialert(app.UIFigure,'Falha ao obter frame inicial da câmara.','Erro');
                return;
            end
            
            % Criar/Atualizar handle da imagem no UIAxes
            if isempty(app.hLiveImg) || ~isgraphics(app.hLiveImg)
                app.hLiveImg = imshow(frame, 'Parent', app.UIAxes);
            else
                set(app.hLiveImg, 'CData', frame);
            end
        
            % Loop live (atualiza CData)
            while app.live
                try
                    frame = snapshot(app.video);
                catch
                    break;  % erro/fecho da câmara
                end
        
                if ~isgraphics(app.hLiveImg)
                    break;  % UIAxes/objeto destruíd
                end
        
                set(app.hLiveImg, 'CData', frame);
                drawnow limitrate;
                pause(0.03);
            end
        
            % Fechar câmara (cleanup único)
            if ~isempty(app.video)
                try
                    delete(app.video);
                catch
                end
                app.video = [];
            end
        
            % Restaurar estados de botões e flag
            app.live = false;
            app.StartLiveButton.Enable = "on";
            app.StopLiveButton.Enable = "off";
            app.CapturarfotoButton.Enable = "off";
        end

        % Button pushed function: StopLiveButton
        function StopLiveButtonPushed(app, event)
            % Parar loop live (StartLiveButtonPushed termina e faz cleanup)
            app.live = false;

            % Restaurar botões principais
            app.StartLiveButton.Enable = "on";
            app.SelecionarfotoButton.Enable = "on";
            app.StopLiveButton.Enable = "off";
            app.CapturarfotoButton.Enable = "off";
        end

        % Button pushed function: CapturarfotoButton
        function CapturarfotoButtonPushed(app, event)
            % Para o live (o loop no StartLive vai sair)
            app.live = false;
            
            % Permitir processar mapa após snapshot
            app.ProcessarimagemdomapaButton.Enable = "on";

            % Tenta tirar um último frame com segurança
            try
                frame = snapshot(app.video);
            catch
                uialert(app.UIFigure,'Não foi possível capturar o frame (câmara já estava a fechar).','Erro');
                return;
            end
        
            % Guarda snapshot para calibração
            app.lastSnapshotRGB = frame;
        
            % Guardar em ficheiro (se quiseres)
            pasta = fileparts(mfilename('fullpath'));
            nome  = fullfile(pasta, "img_" + app.newimg + ".jpeg");
            app.newimg = app.newimg + 1;
            imwrite(frame, nome);

            % Sair do modo live
            app.StartLiveButton.Enable = "on";
            app.StopLiveButton.Enable = "off";
            app.CapturarfotoButton.Enable = "off";
            
            % Desbloquear calibrações e debug do sinal
            app.CalibrarARENAButton.Enable = "on";
            app.CalibrarBASE1Button.Enable = "on";
            app.CalibrarBASE2Button.Enable = "on";
            app.CalibrarBASE3Button.Enable = "on";
            app.CalibrarBASE4Button.Enable = "on";
            app.CalibrarVERMELHOButton.Enable = "on";
            app.CalibrarAZULButton.Enable = "on";
            app.CalibrarBrancoButton.Enable = "on";
            app.ProcessarimagemdosinalButton.Enable = "on";
            
            % Mostra o frame congelado
            if isempty(app.hLiveImg) || ~isgraphics(app.hLiveImg)
                app.hLiveImg = imshow(frame, 'Parent', app.UIAxes);
            else
                set(app.hLiveImg, 'CData', frame);
            end
            
            % Permitir escolher outra foto
            app.SelecionarfotoButton.Enable = "on";
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % Logger para o TextArea 
            logFcn = @(s) app.Log(s);

            % Inicializar robo
            app.robot = RobotConfiguration(app);

            % Conversão de mmppx <-> mppx
            mPerPix = app.mmPerPixel/1000;
            posInicialM = px2m(app, app.robotCentroid_px, app.origin_px, mPerPix);
        
            % Inicializar state com pose da câmara + encoders atuais
            state = []; 
            state.x = posInicialM(1);
            state.y = posInicialM(2);
            state.th = app.theta0;
            state.lastDegL = double(readRotation(app.robot.leftMotor));
            state.lastDegR = double(readRotation(app.robot.rightMotor));
            
            % Posição atual em pixels
            current_px = app.robotCentroid_px;
        
            % Waypoints das bases em metros
            nBases = numel(app.waypointsBases_px);
            waypointsBases_m = cell(size(app.waypointsBases_px));
            for b = 1:nBases
                waypointsBases_m{b} = px2m(app, app.waypointsBases_px{b}, app.origin_px, mPerPix);
            end

            % Cantos das bases em metros (contorno/manobra)
            cantosBases_m = cell(size(app.cantosBases));
            for b = 1:numel(app.cantosBases)
                cantosBases_m{b} = px2m(app, app.cantosBases{b}, app.origin_px, mPerPix);
            end
            
            % CICLO POR CADA BASE
            for baseIdx = 1:4
                logFcn(sprintf("A ir para a base %d...", baseIdx));
        
                % Escolher waypoint de chegada (px) mais próximo da base
                [target_px, ~] = GetClosestWaypoint(app, current_px, app.waypointsBases_px{baseIdx});
        
                % Planear caminho seguro (px) no freeZoneMap
                path_px = PlanSafePath(app, app.freeZoneMap, current_px, target_px);
        
                % Mostrar caminho planeado no mapa
                if ~isempty(app.viz) && exist('VizPlotPlannedPath','file')
                    VizPlotPlannedPath(app, app.viz, path_px);
                end
        
                % Executar trajeto (m) com FollowWaypoints
                path_m = px2m(app, path_px, app.origin_px, mPerPix);
                [finalPose, state] = FollowWaypoints(app, app.robot, path_m, app.theta0, state, app.viz);
        
                % Atualizar posição atual (px) para a próxima iteração
                app.theta0     = finalPose(3);
                current_px = m2px(app, finalPose(1:2), app.origin_px, mPerPix);
        
                % Waypoints da base atual (m) e centro médio da base
                Wb = waypointsBases_m{baseIdx};
                if size(Wb,2) ~= 2 && size(Wb,1) == 2
                    Wb = Wb.';
                end
                baseCenter_m = mean(Wb, 1);
                
                % Escolher o waypoint da base mais próximo da pose final
                p_m = finalPose(1:2);   
                p_m = p_m(:).';
                distancias = vecnorm(Wb - p_m, 2, 2);
                [~, idxWaypointMaisProximo] = min(distancias);
                waypointAlvo_m = Wb(idxWaypointMaisProximo, :);
                
                logFcn(sprintf(">>> Chegou próximo da Base %d <<<", baseIdx));
                logFcn(sprintf("Waypoint alvo (mais próximo): [%.3f, %.3f] (índice %d)\n", waypointAlvo_m(1), waypointAlvo_m(2), idxWaypointMaisProximo));
               
                % Correção da posição automática
                [finalPose, state, correcaoOk] = CorrigirPosicaoAutomaticamente( app, app.robot, state, app.viz, waypointAlvo_m);
                
                % Sincronizar state com a posição devolvida
                state.x = finalPose(1);
                state.y = finalPose(2);
                state.th = finalPose(3);
                app.theta0 = state.th;
                
                pause(1);
                
                % Alinhar robô a olhar para o centro da base (rotação pura)
                [~, state] = RotateToFacePoint(app, app.robot, baseCenter_m, state, app.viz);

                finalPose = [state.x; state.y; state.th];
                app.theta0 = finalPose(3);
                current_px = m2px(app, finalPose(1:2), app.origin_px, mPerPix);
                
                % Confimação manual
                fprintf('\n>>> Correção feita <<<\n');
                fprintf('Está na posição correta? (s/n) [ENTER = sim]: ');
                resposta = input('', 's');
                resposta = lower(strtrim(resposta));

                % ENTER conta como "sim" -> evita que siga para as próximas tentativas
                if isempty(resposta) || any(strcmp(resposta, {'s','sim','y','yes'}))
                    confirm = true;
                else 
                    confirm = false;
                end
                
                % Repetir correção até confirmar
                if confirm == false
                    while confirm == false
                        [finalPose, state, correcaoOk] = CorrigirPosicaoAutomaticamente( app, app.robot, state, app.viz, waypointAlvo_m);

                        state.x = finalPose(1);
                        state.y = finalPose(2);
                        state.th = finalPose(3);
                        app.theta0 = state.th;
                        
                        pause(1);
                        
                        [~, state] = RotateToFacePoint(app, app.robot, baseCenter_m, state, app.viz);
        
                        finalPose = [state.x; state.y; state.th];
                        app.theta0 = finalPose(3);
                        current_px = m2px(app, finalPose(1:2), app.origin_px, mPerPix);

                        fprintf('\n>>> Correção feita <<<\n');
                        fprintf('Está na posição correta? (s/n) [ENTER = sim]: ');
                        resposta = input('', 's');
                        resposta = lower(strtrim(resposta));
        
                        % ENTER conta como "sim" -> evita que siga para as próximas tentativas
                        if isempty(resposta) || any(strcmp(resposta, {'s','sim','y','yes'}))
                            confirm = true;
                        end
                    end
                end
                
                % Log do resultado da correção automática
                if correcaoOk
                    logFcn(sprintf("Correção auto OK. Nova posição: x=%.3f, y=%.3f\n\n", finalPose(1), finalPose(2)));
                else
                    logFcn(sprintf("Correção auto feita (fora tolerância). Posição: x=%.3f, y=%.3f\n\n", finalPose(1), finalPose(2)));
                end
                
                % Encontrar waypoint da base mais próximo da posição atual
                p_m = finalPose(1:2);
                p_m = p_m(:).';             % garantir formato linha [x, y]
                
                % Calcular distâncias para os 4 waypoints (N, S, O, E)
                distancias = vecnorm(Wb - p_m, 2, 2);
                [~, idxWaypointMaisProximo] = min(distancias);
                
                % Posição conhecida do waypoint mais próximo
                posicaoConhecida_m = Wb(idxWaypointMaisProximo, :);

                % Reset da odometria para waypoint conhecido
                logFcn(sprintf('\n>>> Reset de posição antes de detetar sinal <<<\n'));
                logFcn(sprintf('Waypoint mais próximo: %d (N=1, S=2, O=3, E=4)\n', idxWaypointMaisProximo));
                state = ResetOdometryAtBase(app, state, posicaoConhecida_m, baseIdx);   % reset só x,y (theta mantém)
                
                % Sincronizar posição
                finalPose(1) = state.x;
                finalPose(2) = state.y;
                finalPose(3) = state.th;  
                app.theta0   = state.th;
                
                % Atualizar variáveis de controlo com posição resetada
                current_px = m2px(app, [state.x; state.y], app.origin_px, mPerPix);
                finalPose(1) = state.x;
                finalPose(2) = state.y;
                finalPose(3) = state.th;
                app.theta0 = finalPose(3);
                
                % Deteção do sinal (c/ rotação)
                [sinal, finalPose, state] = DetetarSinalComRotacao(app, app.robot, state, app.viz, finalPose, ...
                    'App', app, ...
                    'BaseIdx', baseIdx, ...
                    'SnapshotFcn', @() snapshot(app.video), ...
                    'LogFcn', @(s) app.Log(s));
                        
                app.theta0     = finalPose(3);
                current_px = m2px(app, finalPose(1:2), app.origin_px, mPerPix);
        
                logFcn("Sinal: " + upper(string(sinal)));
                
                % Decisão por sinal
                switch upper(string(sinal))
        
                    case "STOP"
                        logFcn(sprintf("STOP na Base %d", baseIdx));
                        break;
        
                    case {"ESQUERDA","DIREITA"}
                        % Identifica o waypoint de chegada (apenas entre os 4 centrais)
                        W_faces = waypointsBases_m{baseIdx};
                        p_m = finalPose(1:2).';
                        [~, idxChegada] = min(vecnorm(W_faces - p_m, 2, 2));
                    
                        % Obtém trajetória de contorno usando os cantos
                        cantos_m = cantosBases_m{baseIdx};
                        [trajManobra_m, ~] = DefineRoute(app, idxChegada, char(sinal), cantos_m);
                        
                        % Executar contorno canto a canto
                        for k = 1:size(trajManobra_m,1)
                            goal_m = trajManobra_m(k,:);
                            
                            logFcn(sprintf("Antes contorno: app.theta0=%.1f  state.th=%.1f  finalPose.th=%.1f\n", rad2deg(app.theta0), rad2deg(state.th), rad2deg(finalPose(3))));

                            % Rodar no sítio para o próximo canto
                            [finalPose, state] = RotateToFacePoint(app, app.robot, goal_m, state, app.viz);
        
                            % Sincronização crítica de estado após a rotação
                            app.theta0 = finalPose(3);
                            state.th = finalPose(3);
                            
                            % Avançar em linha reta até à extremidade
                            seg_m = [finalPose(1:2).'; goal_m]; 
                            [finalPose, state] = FollowWaypoints(app, app.robot, seg_m, finalPose(3), state, app.viz);
                    
                            app.theta0 = finalPose(3);
                            current_px = m2px(app, finalPose(1:2), app.origin_px, mPerPix);
                        end
                end
            end
        end

        % Button pushed function: CalibrarARENAButton
        function CalibrarARENAButtonPushed(app, event)
            CalibrateColor(app, 'BM', 'arena', 'k', 10);
        end

        % Button pushed function: CalibrarBASE1Button
        function CalibrarBASE1ButtonPushed(app, event)
            CalibrateColor(app, 'RM', 'vermelho', 'r', 10);
        end

        % Button pushed function: CalibrarBASE2Button
        function CalibrarBASE2ButtonPushed(app, event)
            CalibrateColor(app, 'AM', 'amarelo', 'y', 10);
        end

        % Button pushed function: CalibrarBASE3Button
        function CalibrarBASE3ButtonPushed(app, event)
            CalibrateColor(app, 'GM', 'verde', 'g', 10);
        end

        % Button pushed function: CalibrarBASE4Button
        function CalibrarBASE4ButtonPushed(app, event)
            CalibrateColor(app, 'LM', 'lilas', 'm', 10);
        end

        % Button pushed function: CalibrarAZULButton
        function CalibrarAZULButtonPushed(app, event)
            CalibrateColor(app, 'AS', 'azul', 'b', 10);
            app.ProcessarimagemdosinalButton.Enable = "on";
        end

        % Button pushed function: CalibrarBrancoButton
        function CalibrarBrancoButtonPushed(app, event)
            CalibrateColor(app, 'BS', 'branco', 'w', 10);
            app.ProcessarimagemdosinalButton.Enable = "on";
        end

        % Button pushed function: CalibrarVERMELHOButton
        function CalibrarVERMELHOButtonPushed(app, event)
            CalibrateColor(app, 'VS', 'vermelho', 'r', 10);
            app.ProcessarimagemdosinalButton.Enable = "on";
        end

        % Button pushed function: ProcessarimagemdomapaButton
        function ProcessarimagemdomapaButtonPushed(app, event)
            [app.freeZoneMap, app.waypointsBases_px, app.InflatedBases,app.robotCentroid_px, app.mmPerPixel,app.boardMask,app.centroidePurple,app.centroideYellow,app.cantosBases] = ProcessedMap(app, app.imgFile, true);
        end

        % Button pushed function: CarregarmapaButton
        function CarregarmapaButtonPushed(app, event)
            % Abrir janela para escolher ficheiro
            filtros = {'*.jpg;*.jpeg;*.png;*.bmp','Imagens (*.jpg, *.png, *.bmp)'; ...
                       '*.*','Todos os ficheiros'};
            [file, path] = uigetfile(filtros, 'Escolhe a foto da arena', pwd);
        
            % Cancelar
            if isequal(file,0)
                return;
            end
        
            % Guardar path da imagem
            app.imgFile = fullfile(path, file);
            
            % Processar mapa + Inicializar visualização/variáveis principais
            [app.freeZoneMap, app.waypointsBases_px, app.robotCentroid_px, app.mmPerPixel, app.viz,app.origin_px,app.theta0,app.cantosBases] = InitVizMapa(app, app.imgFile, app.UIAxes_Mapa_Simuldo);
        end

        % Button pushed function: SelecionarfotoButton
        function SelecionarfotoButtonPushed(app, event)
            % Abrir janela para escolher ficheiro
            filtros = {'*.jpg;*.jpeg;*.png;*.bmp','Imagens (*.jpg, *.png, *.bmp)'; ...
                       '*.*','Todos os ficheiros'};
            [file, path] = uigetfile(filtros, 'Escolhe a foto da arena', pwd);
        
            % Cancelar
            if isequal(file,0)
                return;
            end
            
            % Guardar path e ler imagem
            app.imgFile = fullfile(path, file);
            map = imread(app.imgFile);
            
            % snapshot (para calibrar HSV)
            app.lastSnapshotRGB = map;
        
            % Mostrar no UIAxes
            imshow(map, 'Parent', app.UIAxes);
            
            % Desbloquear calibrações e processamento
            app.CalibrarARENAButton.Enable = "on";
            app.CalibrarBASE1Button.Enable = "on";
            app.CalibrarBASE2Button.Enable = "on";
            app.CalibrarBASE3Button.Enable = "on";
            app.CalibrarBASE4Button.Enable = "on";
            app.CalibrarVERMELHOButton.Enable = "on";
            app.CalibrarAZULButton.Enable = "on";
            app.CalibrarBrancoButton.Enable = "on";
            app.ProcessarimagemdomapaButton.Enable = "on";
        end

        % Button pushed function: ProcessarimagemdosinalButton
        function ProcessarimagemdosinalButtonPushed(app, event)
            IM = app.lastSnapshotRGB;
        
            imshow(IM, 'Parent', app.UIAxes_Sinal_Foto_Original);
            HSV = rgb2hsv(IM);
            H = HSV(:,:,1);
            S = HSV(:,:,2);
            V = HSV(:,:,3);
        
            % --- INICIALIZAR máscaras finais (para plot sempre existir) ---
            mascaraMaiorVermelho     = false(size(H));
            brancoDentroDoVermelho   = false(size(H));
            mascaraAzulPreenchido    = false(size(H));
            whiteLargest             = false(size(H));
        
            % --- Ler thresholds (branco e azul assumidos como existentes) ---
            BS = getHSVthr(app,'BS'); % sinal branco
            AS = getHSVthr(app,'AS'); % sinal azul
        
            % --- Vermelho: pode não estar calibrado -> não pode bloquear debug ---
            try
                VS = getHSVthr(app,'VS'); % sinal vermelho
                mascaraVermelha = hsvMask(app, H,S,V, VS);
                mascaraVermelha = bwareaopen(mascaraVermelha,200);
            catch
                VS = [];
                mascaraVermelha = false(size(H));
            end
        
            % --- Máscaras branco/azul ---
            mascaraBranca = hsvMask(app, H,S,V, BS);
        
            mascaraAzul = hsvMask(app, H,S,V, AS);
            mascaraAzul = bwareaopen(mascaraAzul,200);
        
            % --- Vermelho: maior objeto (se existir) ---
            objetosVermelho = bwlabel(mascaraVermelha);
            carV = regionprops(objetosVermelho,'Area');
        
            if ~isempty(carV)
                [~, idxV] = max([carV.Area]);
                mascaraMaiorVermelho = (objetosVermelho == idxV);
                mascaraMaiorVermelho = imfill(mascaraMaiorVermelho, "holes");
                brancoDentroDoVermelho = mascaraMaiorVermelho & mascaraBranca;
            end
        
            % --- Azul: maior objeto + fechar + preencher (se existir) ---
            objetosAzuis = bwlabel(mascaraAzul);
            carA = regionprops(objetosAzuis,'Area');
        
            if ~isempty(carA)
                [~, idxA] = max([carA.Area]);
                mascaraMaiorAzul = (objetosAzuis == idxA);
        
                mascaraAzulFechado = imclose(mascaraMaiorAzul, strel('disk',15));
                mascaraAzulPreenchido = imfill(mascaraAzulFechado,"holes");
        
                % --- Branco dentro do azul (usa BS) ---
                roiBlue = mascaraAzulPreenchido;
                whiteInBlue = roiBlue & (S <= BS.smax) & (V >= BS.vmin);
        
                ccW = bwconncomp(whiteInBlue);
                if ccW.NumObjects > 0
                    areasW = cellfun(@numel, ccW.PixelIdxList);
                    [~, idxMaxW] = max(areasW);
        
                    whiteLargest = false(size(whiteInBlue));
                    whiteLargest(ccW.PixelIdxList{idxMaxW}) = true;
                    whiteLargest = imfill(whiteLargest, 'holes');
                end
            end
    
            % --- DEBUG: FIGURE com as 4 máscaras (mesmo sem vermelho calibrado) ---
            fig = figure('Name','Debug - Máscaras Sinais', ...
                         'NumberTitle','off', 'Color','w');
            t = tiledlayout(fig, 1, 4, 'TileSpacing','compact', 'Padding','compact');
        
            nexttile(t); imshow(mascaraMaiorVermelho);  title('Vermelho (maior objeto)');
            nexttile(t); imshow(brancoDentroDoVermelho);title('Branco dentro do vermelho');
            nexttile(t); imshow(mascaraAzulPreenchido); title('Azul (preenchido)');
            nexttile(t); imshow(whiteLargest);          title('Branco dentro do azul');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigsTab = uitab(app.TabGroup);
            app.ConfigsTab.Title = 'Configs';
            app.UIAxes = uiaxes(app.ConfigsTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.Tag = 'UIAxes';
            app.Image2 = uiimage(app.ConfigsTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'baby-blue.png');
            app.Image_5 = uiimage(app.ConfigsTab);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'white.png');
            app.Image_4 = uiimage(app.ConfigsTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'white.png');
            app.Image_3 = uiimage(app.ConfigsTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'white.png');
            app.Image_2 = uiimage(app.ConfigsTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'white.png');
            app.Image = uiimage(app.ConfigsTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'white.png');
            app.CamaraconnectionTresholdseArenaLabel = uilabel(app.ConfigsTab);
            app.CamaraconnectionTresholdseArenaLabel.Text = 'Camara connection - Tresholds e Arena';
            app.StartLiveButton = uibutton(app.ConfigsTab, 'push');
            app.StartLiveButton.ButtonPushedFcn = createCallbackFcn(app, @StartLiveButtonPushed, true);
            app.StartLiveButton.Tag = 'LiveButton';
            app.StartLiveButton.Text = 'Start Live';
            app.CapturarfotoButton = uibutton(app.ConfigsTab, 'push');
            app.CapturarfotoButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarfotoButtonPushed, true);
            app.CapturarfotoButton.Tag = 'CapturarfotodaarenaButton';
            app.CapturarfotoButton.Enable = 'off';
            app.CapturarfotoButton.Text = 'Capturar foto';
            app.IPEditFieldLabel = uilabel(app.ConfigsTab);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.ConfigsTab, 'text');
            app.IPEditField.Tag = 'IPEditField';
            app.IPEditField.Value = '100.117.171.4';
            app.UserEditFieldLabel = uilabel(app.ConfigsTab);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.ConfigsTab, 'text');
            app.UserEditField.Tag = 'UserEditField';
            app.UserEditField.Value = 'admin';
            app.TresholdsLabel = uilabel(app.ConfigsTab);
            app.TresholdsLabel.Text = 'Tresholds';
            app.ProcessarimagemdomapaButton = uibutton(app.ConfigsTab, 'push');
            app.ProcessarimagemdomapaButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarimagemdomapaButtonPushed, true);
            app.ProcessarimagemdomapaButton.Tag = 'ProcessarimagemButton';
            app.ProcessarimagemdomapaButton.Enable = 'off';
            app.ProcessarimagemdomapaButton.Text = 'Processar imagem do mapa';
            app.BrancoLabel = uilabel(app.ConfigsTab);
            app.BrancoLabel.Text = 'Branco';
            app.AzulLabel = uilabel(app.ConfigsTab);
            app.AzulLabel.Text = 'Azul';
            app.VermelhoLabel = uilabel(app.ConfigsTab);
            app.VermelhoLabel.Text = 'Vermelho';
            app.MAPALabel = uilabel(app.ConfigsTab);
            app.MAPALabel.Text = 'MAPA';
            app.VermelhoLabel_2 = uilabel(app.ConfigsTab);
            app.VermelhoLabel_2.Text = 'Vermelho';
            app.VerdeLabel = uilabel(app.ConfigsTab);
            app.VerdeLabel.Text = 'Verde';
            app.AmareloLabel = uilabel(app.ConfigsTab);
            app.AmareloLabel.Text = 'Amarelo';
            app.LilasLabel = uilabel(app.ConfigsTab);
            app.LilasLabel.Text = 'Lilas';
            app.BrancoLabel_2 = uilabel(app.ConfigsTab);
            app.BrancoLabel_2.Text = 'Branco';
            app.SINAISLabel = uilabel(app.ConfigsTab);
            app.SINAISLabel.Text = 'SINAIS';
            app.HminLabel = uilabel(app.ConfigsTab);
            app.HminLabel.Text = 'H min';
            app.HmaxLabel = uilabel(app.ConfigsTab);
            app.HmaxLabel.Text = 'H max';
            app.SminLabel = uilabel(app.ConfigsTab);
            app.SminLabel.Text = 'S min';
            app.SmaxLabel = uilabel(app.ConfigsTab);
            app.SmaxLabel.Text = 'S max';
            app.VminLabel = uilabel(app.ConfigsTab);
            app.VminLabel.Text = 'V min';
            app.VmaxLabel = uilabel(app.ConfigsTab);
            app.VmaxLabel.Text = 'V max';
            app.CalibrarBASE1Button = uibutton(app.ConfigsTab, 'push');
            app.CalibrarBASE1Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrarBASE1ButtonPushed, true);
            app.CalibrarBASE1Button.Tag = 'CalibrarBASE1Button';
            app.CalibrarBASE1Button.Enable = 'off';
            app.CalibrarBASE1Button.Text = 'Calibrar BASE 1';
            app.CalibrarBASE3Button = uibutton(app.ConfigsTab, 'push');
            app.CalibrarBASE3Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrarBASE3ButtonPushed, true);
            app.CalibrarBASE3Button.Tag = 'CalibrarBASE3Button';
            app.CalibrarBASE3Button.Enable = 'off';
            app.CalibrarBASE3Button.Text = 'Calibrar BASE 3';
            app.CalibrarBASE4Button = uibutton(app.ConfigsTab, 'push');
            app.CalibrarBASE4Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrarBASE4ButtonPushed, true);
            app.CalibrarBASE4Button.Tag = 'CalibrarBASE4Button';
            app.CalibrarBASE4Button.Enable = 'off';
            app.CalibrarBASE4Button.Text = 'Calibrar BASE 4';
            app.CalibrarBASE2Button = uibutton(app.ConfigsTab, 'push');
            app.CalibrarBASE2Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrarBASE2ButtonPushed, true);
            app.CalibrarBASE2Button.Tag = 'CalibrarBASE2Button';
            app.CalibrarBASE2Button.Enable = 'off';
            app.CalibrarBASE2Button.Text = 'Calibrar BASE 2';
            app.CalibrarAZULButton = uibutton(app.ConfigsTab, 'push');
            app.CalibrarAZULButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarAZULButtonPushed, true);
            app.CalibrarAZULButton.Tag = 'CalibrarAZULButton';
            app.CalibrarAZULButton.Enable = 'off';
            app.CalibrarAZULButton.Text = 'Calibrar AZUL';
            app.CalibrarVERMELHOButton = uibutton(app.ConfigsTab, 'push');
            app.CalibrarVERMELHOButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarVERMELHOButtonPushed, true);
            app.CalibrarVERMELHOButton.Tag = 'CalibrarVERMELHOButton';
            app.CalibrarVERMELHOButton.Enable = 'off';
            app.CalibrarVERMELHOButton.Text = 'Calibrar VERMELHO';
            app.CalibrarBrancoButton = uibutton(app.ConfigsTab, 'push');
            app.CalibrarBrancoButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarBrancoButtonPushed, true);
            app.CalibrarBrancoButton.Tag = 'CalibrarBrancoButton';
            app.CalibrarBrancoButton.Enable = 'off';
            app.CalibrarBrancoButton.Text = 'Calibrar Branco';
            app.BM_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.BM_H_min.ValueDisplayFormat = '%.2f';
            app.BM_H_min.Tag = 'BM_H_min';
            app.BM_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.BM_H_max.ValueDisplayFormat = '%.2f';
            app.BM_H_max.Tag = 'BM_H_max';
            app.BM_H_max.Value = 1;
            app.BM_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.BM_S_min.ValueDisplayFormat = '%.2f';
            app.BM_S_min.Tag = 'BM_S_min';
            app.BM_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.BM_S_max.ValueDisplayFormat = '%.2f';
            app.BM_S_max.Tag = 'BM_S_max';
            app.BM_S_max.Value = 0.25;
            app.BM_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.BM_V_min.ValueDisplayFormat = '%.2f';
            app.BM_V_min.Tag = 'BM_V_min';
            app.BM_V_min.Value = 0.65;
            app.BM_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.BM_V_max.ValueDisplayFormat = '%.2f';
            app.BM_V_max.Tag = 'BM_V_max';
            app.BM_V_max.Value = 1;
            app.RM_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.RM_H_min.ValueDisplayFormat = '%.2f';
            app.RM_H_min.Tag = 'RM_H_min';
            app.RM_H_min.Value = 0.9;
            app.RM_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.RM_H_max.ValueDisplayFormat = '%.2f';
            app.RM_H_max.Tag = 'RM_H_max';
            app.RM_H_max.Value = 0.07;
            app.RM_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.RM_S_min.ValueDisplayFormat = '%.2f';
            app.RM_S_min.Tag = 'RM_S_min';
            app.RM_S_min.Value = 0.6;
            app.RM_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.RM_S_max.ValueDisplayFormat = '%.2f';
            app.RM_S_max.Tag = 'RM_S_max';
            app.RM_S_max.Value = 1;
            app.RM_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.RM_V_min.ValueDisplayFormat = '%.2f';
            app.RM_V_min.Tag = 'RM_V_min';
            app.RM_V_min.Value = 0.25;
            app.RM_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.RM_V_max.ValueDisplayFormat = '%.2f';
            app.RM_V_max.Tag = 'RM_V_max';
            app.RM_V_max.Value = 1;
            app.AM_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.AM_H_min.ValueDisplayFormat = '%.2f';
            app.AM_H_min.Tag = 'AM_H_min';
            app.AM_H_min.Value = 0.1;
            app.AM_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.AM_H_max.ValueDisplayFormat = '%.2f';
            app.AM_H_max.Tag = 'AM_H_max';
            app.AM_H_max.Value = 0.2;
            app.AM_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.AM_S_min.ValueDisplayFormat = '%.2f';
            app.AM_S_min.Tag = 'AM_S_min';
            app.AM_S_min.Value = 0.4;
            app.AM_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.AM_S_max.ValueDisplayFormat = '%.2f';
            app.AM_S_max.Tag = 'AM_S_max';
            app.AM_S_max.Value = 1;
            app.AM_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.AM_V_min.ValueDisplayFormat = '%.2f';
            app.AM_V_min.Tag = 'AM_V_min';
            app.AM_V_min.Value = 0.4;
            app.AM_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.AM_V_max.ValueDisplayFormat = '%.2f';
            app.AM_V_max.Tag = 'AM_V_max';
            app.AM_V_max.Value = 1;
            app.GM_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.GM_H_min.ValueDisplayFormat = '%.2f';
            app.GM_H_min.Tag = 'GM_H_min';
            app.GM_H_min.Value = 0.25;
            app.GM_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.GM_H_max.ValueDisplayFormat = '%.2f';
            app.GM_H_max.Tag = 'GM_H_max';
            app.GM_H_max.Value = 0.45;
            app.GM_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.GM_S_min.ValueDisplayFormat = '%.2f';
            app.GM_S_min.Tag = 'GM_S_min';
            app.GM_S_min.Value = 0.3;
            app.GM_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.GM_S_max.ValueDisplayFormat = '%.2f';
            app.GM_S_max.Tag = 'GM_S_max';
            app.GM_S_max.Value = 1;
            app.GM_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.GM_V_min.ValueDisplayFormat = '%.2f';
            app.GM_V_min.Tag = 'GM_V_min';
            app.GM_V_min.Value = 0.2;
            app.GM_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.GM_V_max.ValueDisplayFormat = '%.2f';
            app.GM_V_max.Tag = 'GM_V_max';
            app.GM_V_max.Value = 1;
            app.LM_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.LM_H_min.ValueDisplayFormat = '%.2f';
            app.LM_H_min.Tag = 'LM_H_min';
            app.LM_H_min.Value = 0.63;
            app.LM_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.LM_H_max.ValueDisplayFormat = '%.2f';
            app.LM_H_max.Tag = 'LM_H_max';
            app.LM_H_max.Value = 0.9;
            app.LM_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.LM_S_min.ValueDisplayFormat = '%.2f';
            app.LM_S_min.Tag = 'LM_S_min';
            app.LM_S_min.Value = 0.08;
            app.LM_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.LM_S_max.ValueDisplayFormat = '%.2f';
            app.LM_S_max.Tag = 'LM_S_max';
            app.LM_S_max.Value = 0.7;
            app.LM_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.LM_V_min.ValueDisplayFormat = '%.2f';
            app.LM_V_min.Tag = 'LM_V_min';
            app.LM_V_min.Value = 0.1;
            app.LM_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.LM_V_max.ValueDisplayFormat = '%.2f';
            app.LM_V_max.Tag = 'LM_V_max';
            app.LM_V_max.Value = 0.85;
            app.VS_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.VS_H_min.ValueDisplayFormat = '%.2f';
            app.VS_H_min.Tag = 'VS_H_min';
            app.VS_H_min.Value = 0.94;
            app.VS_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.VS_H_max.ValueDisplayFormat = '%.2f';
            app.VS_H_max.Tag = 'VS_H_max';
            app.VS_H_max.Value = 0.09;
            app.VS_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.VS_S_min.ValueDisplayFormat = '%.2f';
            app.VS_S_min.Tag = 'VS_S_min';
            app.VS_S_min.Value = 0.5;
            app.VS_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.VS_S_max.ValueDisplayFormat = '%.2f';
            app.VS_S_max.Tag = 'VS_S_max';
            app.VS_S_max.Value = 1;
            app.VS_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.VS_V_min.ValueDisplayFormat = '%.2f';
            app.VS_V_min.Tag = 'VS_V_min';
            app.VS_V_min.Value = 0.25;
            app.VS_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.VS_V_max.ValueDisplayFormat = '%.2f';
            app.VS_V_max.Tag = 'VS_V_max';
            app.VS_V_max.Value = 1;
            app.AS_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.AS_H_min.ValueDisplayFormat = '%.2f';
            app.AS_H_min.Tag = 'AS_H_min';
            app.AS_H_min.Value = 0.49;
            app.AS_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.AS_H_max.ValueDisplayFormat = '%.2f';
            app.AS_H_max.Tag = 'AS_H_max';
            app.AS_H_max.Value = 0.69;
            app.AS_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.AS_S_min.ValueDisplayFormat = '%.2f';
            app.AS_S_min.Tag = 'AS_S_min';
            app.AS_S_min.Value = 0.24;
            app.AS_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.AS_S_max.ValueDisplayFormat = '%.2f';
            app.AS_S_max.Tag = 'AS_S_max';
            app.AS_S_max.Value = 1;
            app.AS_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.AS_V_min.ValueDisplayFormat = '%.2f';
            app.AS_V_min.Tag = 'AS_V_min';
            app.AS_V_min.Value = 0.19;
            app.AS_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.AS_V_max.ValueDisplayFormat = '%.2f';
            app.AS_V_max.Tag = 'AS_V_max';
            app.AS_V_max.Value = 1;
            app.BS_H_min = uieditfield(app.ConfigsTab, 'numeric');
            app.BS_H_min.ValueDisplayFormat = '%.2f';
            app.BS_H_min.Tag = 'BS_H_min';
            app.BS_H_max = uieditfield(app.ConfigsTab, 'numeric');
            app.BS_H_max.ValueDisplayFormat = '%.2f';
            app.BS_H_max.Tag = 'BS_H_max';
            app.BS_H_max.Value = 1;
            app.BS_S_min = uieditfield(app.ConfigsTab, 'numeric');
            app.BS_S_min.ValueDisplayFormat = '%.2f';
            app.BS_S_min.Tag = 'BS_S_min';
            app.BS_S_max = uieditfield(app.ConfigsTab, 'numeric');
            app.BS_S_max.ValueDisplayFormat = '%.2f';
            app.BS_S_max.Tag = 'BS_S_max';
            app.BS_S_max.Value = 0.45;
            app.BS_V_min = uieditfield(app.ConfigsTab, 'numeric');
            app.BS_V_min.ValueDisplayFormat = '%.2f';
            app.BS_V_min.Tag = 'BS_V_min';
            app.BS_V_min.Value = 0.55;
            app.BS_V_max = uieditfield(app.ConfigsTab, 'numeric');
            app.BS_V_max.ValueDisplayFormat = '%.2f';
            app.BS_V_max.Tag = 'BS_V_max';
            app.BS_V_max.Value = 1;
            app.CalibrarARENAButton = uibutton(app.ConfigsTab, 'push');
            app.CalibrarARENAButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarARENAButtonPushed, true);
            app.CalibrarARENAButton.Tag = 'CalibrarARENAButton';
            app.CalibrarARENAButton.Enable = 'off';
            app.CalibrarARENAButton.Text = 'Calibrar ARENA';
            app.StopLiveButton = uibutton(app.ConfigsTab, 'push');
            app.StopLiveButton.ButtonPushedFcn = createCallbackFcn(app, @StopLiveButtonPushed, true);
            app.StopLiveButton.Tag = 'LiveButton';
            app.StopLiveButton.Enable = 'off';
            app.StopLiveButton.Text = 'Stop Live';
            app.PasswordEditField_2Label = uilabel(app.ConfigsTab);
            app.PasswordEditField_2Label.Text = 'Password';
            app.PasswordEditField = uieditfield(app.ConfigsTab, 'text');
            app.PasswordEditField.Tag = 'PasswordEditField';
            app.PasswordEditField.Value = '12345';
            app.SelecionarfotoButton = uibutton(app.ConfigsTab, 'push');
            app.SelecionarfotoButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarfotoButtonPushed, true);
            app.SelecionarfotoButton.Tag = 'SelecionarfotoButton';
            app.SelecionarfotoButton.Text = 'Selecionar foto';
            app.ProcessarimagemdosinalButton = uibutton(app.ConfigsTab, 'push');
            app.ProcessarimagemdosinalButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarimagemdosinalButtonPushed, true);
            app.ProcessarimagemdosinalButton.Tag = 'ProcessarimagemdosinalButton';
            app.ProcessarimagemdosinalButton.Enable = 'off';
            app.ProcessarimagemdosinalButton.Text = 'Processar imagem do sinal';
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.UIAxes_Sinal_Foto_Original = uiaxes(app.RunTab);
            title(app.UIAxes_Sinal_Foto_Original, 'Foto ')
            app.UIAxes_Sinal_Foto_Original.XTick = [];
            app.UIAxes_Sinal_Foto_Original.YTick = [];
            app.UIAxes_Sinal_Foto_Original.ZTick = [];
            app.UIAxes_Sinal_Foto_Original.Tag = 'UIAxes_Sinal_Foto_Original';
            app.UIAxes_Mapa_Simuldo = uiaxes(app.RunTab);
            title(app.UIAxes_Mapa_Simuldo, 'Mapa')
            app.UIAxes_Mapa_Simuldo.XTick = [];
            app.UIAxes_Mapa_Simuldo.YTick = [];
            app.UIAxes_Mapa_Simuldo.ZTick = [];
            app.UIAxes_Mapa_Simuldo.Tag = 'UIAxes_Mapa_Simuldo';
            app.UIAxes_Mascara_Vermelho = uiaxes(app.RunTab);
            app.UIAxes_Mascara_Vermelho.XTick = [];
            app.UIAxes_Mascara_Vermelho.YTick = [];
            app.UIAxes_Mascara_Vermelho.ZTick = [];
            app.UIAxes_Mascara_Vermelho.Tag = 'UIAxes_Mascara_Vermelho';
            app.UIAxes_Mascara_Azul = uiaxes(app.RunTab);
            app.UIAxes_Mascara_Azul.XTick = [];
            app.UIAxes_Mascara_Azul.YTick = [];
            app.UIAxes_Mascara_Azul.ZTick = [];
            app.UIAxes_Mascara_Azul.Tag = 'UIAxes_Mascara_Azul';
            app.UIAxes_Mascara_Branco = uiaxes(app.RunTab);
            app.UIAxes_Mascara_Branco.XTick = [];
            app.UIAxes_Mascara_Branco.YTick = [];
            app.UIAxes_Mascara_Branco.ZTick = [];
            app.UIAxes_Mascara_Branco.Tag = 'UIAxes_Mascara_Branco';
            app.STARTButton = uibutton(app.RunTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tag = 'STARTButton';
            app.STARTButton.Text = 'START';
            app.Image3 = uiimage(app.RunTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'black.png');
            app.LogTextArea = uitextarea(app.RunTab);
            app.LogTextArea.Tag = 'LogTextArea';
            app.LogTextArea.Editable = 'off';
            app.CarregarmapaButton = uibutton(app.RunTab, 'push');
            app.CarregarmapaButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarmapaButtonPushed, true);
            app.CarregarmapaButton.Tag = 'STARTButton';
            app.CarregarmapaButton.Text = 'Carregar mapa';
            app.Image2_2 = uiimage(app.RunTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'baby-blue.png');
            app.CamaraconnectionSinaisLabel = uilabel(app.RunTab);
            app.CamaraconnectionSinaisLabel.Text = 'Camara connection - Sinais';
            app.IPEditField_2Label = uilabel(app.RunTab);
            app.IPEditField_2Label.Text = 'IP';
            app.IPEditField_2 = uieditfield(app.RunTab, 'text');
            app.IPEditField_2.Tag = 'IPEditField';
            app.IPEditField_2.Value = '10.111.72.215';
            app.UserEditField_2Label = uilabel(app.RunTab);
            app.UserEditField_2Label.Text = 'User';
            app.UserEditField_2 = uieditfield(app.RunTab, 'text');
            app.UserEditField_2.Tag = 'UserEditField';
            app.UserEditField_2.Value = 'admin';
            app.PasswordEditField_2Label_2 = uilabel(app.RunTab);
            app.PasswordEditField_2Label_2.Text = 'Password';
            app.PasswordEditField_2 = uieditfield(app.RunTab, 'text');
            app.PasswordEditField_2.Tag = 'PasswordEditField';
            app.PasswordEditField_2.Value = '12345';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end